classdef user_interface < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        GridLayout                     matlab.ui.container.GridLayout
        LeftPanel                      matlab.ui.container.Panel
        TabGroup3                      matlab.ui.container.TabGroup
        ElectrostaticTab               matlab.ui.container.Tab
        TabGroup4                      matlab.ui.container.TabGroup
        CreategeometryTab              matlab.ui.container.Tab
        TabGroup                       matlab.ui.container.TabGroup
        rectangleTab                   matlab.ui.container.Tab
        ActivaterectangleCheckBox      matlab.ui.control.CheckBox
        UITable2                       matlab.ui.control.Table
        LoadButton_2                   matlab.ui.control.Button
        RectanglenumberEditField       matlab.ui.control.NumericEditField
        RectanglenumberEditFieldLabel  matlab.ui.control.Label
        circleTab                      matlab.ui.container.Tab
        ActivatecircleCheckBox         matlab.ui.control.CheckBox
        UITable3                       matlab.ui.control.Table
        LoadButton_3                   matlab.ui.control.Button
        CirclenumberEditField          matlab.ui.control.NumericEditField
        CirclenumberEditFieldLabel     matlab.ui.control.Label
        triangleTab                    matlab.ui.container.Tab
        ActivatetriangleCheckBox       matlab.ui.control.CheckBox
        TrianglenumberTextArea         matlab.ui.control.TextArea
        TrianglenumberTextAreaLabel    matlab.ui.control.Label
        UITable                        matlab.ui.control.Table
        LoadButton                     matlab.ui.control.Button
        parallelplateTab               matlab.ui.container.Tab
        ClearsolutionkeepgeometryButton  matlab.ui.control.Button
        chargedensityofplateQm2EditField  matlab.ui.control.NumericEditField
        chargedensityofoneplateQm2Label  matlab.ui.control.Label
        platethicknessmEditField       matlab.ui.control.NumericEditField
        platethicknessmEditFieldLabel  matlab.ui.control.Label
        ClearallButton                 matlab.ui.control.Button
        relativepermittivityofdielectricEditField  matlab.ui.control.NumericEditField
        relativepermittivityofdielectricLabel  matlab.ui.control.Label
        distancebetweenplatesmEditField  matlab.ui.control.NumericEditField
        distancebetweenplatesmEditFieldLabel  matlab.ui.control.Label
        widthofplatesmEditField        matlab.ui.control.NumericEditField
        widthofplatesmEditFieldLabel   matlab.ui.control.Label
        DrawButton_4                   matlab.ui.control.Button
        coaxialcableTab                matlab.ui.container.Tab
        InnerconductorradiusEditField  matlab.ui.control.NumericEditField
        InnerconductorradiusLabel      matlab.ui.control.Label
        ThicknessofouterringEditField  matlab.ui.control.NumericEditField
        ThicknessofouterringEditFieldLabel  matlab.ui.control.Label
        ThicknessofdielectricmediaEditField  matlab.ui.control.NumericEditField
        ThicknessofdielectricmediaLabel  matlab.ui.control.Label
        ThicknessofinnerringEditField  matlab.ui.control.NumericEditField
        ThicknessofinnerringEditFieldLabel  matlab.ui.control.Label
        RelativepermittivityofdielectricEditField  matlab.ui.control.NumericEditField
        RelativepermittivityofdielectricLabel  matlab.ui.control.Label
        ChargedensityofinnercableEditField  matlab.ui.control.NumericEditField
        ChargedensityofringLabel       matlab.ui.control.Label
        RefreshButton                  matlab.ui.control.Button
        DrawButton_3                   matlab.ui.control.Button
        ImportgeometryTab              matlab.ui.container.Tab
        LoadButton_15                  matlab.ui.control.Button
        FilenameEditField              matlab.ui.control.EditField
        FilenameEditFieldLabel         matlab.ui.control.Label
        ClearallButton_2               matlab.ui.control.Button
        RefreshButton_2                matlab.ui.control.Button
        SolveButton                    matlab.ui.control.Button
        DrawgeometryandmeshButton      matlab.ui.control.Button
        AssignEMpropertiesTab          matlab.ui.container.Tab
        SolveButton_2                  matlab.ui.control.Button
        TabGroup5                      matlab.ui.container.TabGroup
        SourceTab                      matlab.ui.container.Tab
        ActivatesourceCheckBox_2       matlab.ui.control.CheckBox
        LoadButton_4                   matlab.ui.control.Button
        NumberoffacestogivechargedensityEditField  matlab.ui.control.NumericEditField
        NumberoffacestogivechargedensityEditFieldLabel  matlab.ui.control.Label
        UITable4                       matlab.ui.control.Table
        BoundaryconditionTab           matlab.ui.container.Tab
        ActivateboundaryconditionCheckBox  matlab.ui.control.CheckBox
        UITable5                       matlab.ui.control.Table
        LoadButton_5                   matlab.ui.control.Button
        NumberofboundaryconditionEditField  matlab.ui.control.NumericEditField
        NumberofboundaryconditionEditFieldLabel  matlab.ui.control.Label
        FarfieldTab                    matlab.ui.container.Tab
        NumberofabsorbingboundaryconditionEditField_3  matlab.ui.control.NumericEditField
        NumberofabsorbingboundaryconditionEditField_3Label  matlab.ui.control.Label
        LoadButton_17                  matlab.ui.control.Button
        ActivateabsorbingboundaryconditionCheckBox_3  matlab.ui.control.CheckBox
        UITable7_3                     matlab.ui.control.Table
        RelativepermittivityTab        matlab.ui.container.Tab
        TextArea_2                     matlab.ui.control.TextArea
        TextArea_2Label                matlab.ui.control.Label
        UITable6                       matlab.ui.control.Table
        LoadButton_6                   matlab.ui.control.Button
        NumberoffacestogiverelativepermittivityEditField  matlab.ui.control.NumericEditField
        NumberoffacestogiverelativepermittivityLabel  matlab.ui.control.Label
        MagnetostaticTab               matlab.ui.container.Tab
        TabGroup6                      matlab.ui.container.TabGroup
        CreategeometryTab_2            matlab.ui.container.Tab
        ClearallButton_3               matlab.ui.control.Button
        RefreshButton_4                matlab.ui.control.Button
        SolveButton_3                  matlab.ui.control.Button
        DrawgeometryandmeshButton_2    matlab.ui.control.Button
        TabGroup7                      matlab.ui.container.TabGroup
        RectangleTab                   matlab.ui.container.Tab
        RectanglenumberEditField_2     matlab.ui.control.NumericEditField
        RectanglenumberEditField_2Label  matlab.ui.control.Label
        ActivaterectangleCheckBox_2    matlab.ui.control.CheckBox
        UITable2_2                     matlab.ui.control.Table
        LoadButton_8                   matlab.ui.control.Button
        CircleTab                      matlab.ui.container.Tab
        CirclenumberEditField_2        matlab.ui.control.NumericEditField
        CirclenumberEditField_2Label   matlab.ui.control.Label
        ActivatecircleCheckBox_2       matlab.ui.control.CheckBox
        UITable3_2                     matlab.ui.control.Table
        LoadButton_9                   matlab.ui.control.Button
        TriangleTab                    matlab.ui.container.Tab
        TrianglenumberEditField        matlab.ui.control.NumericEditField
        TrianglenumberEditFieldLabel   matlab.ui.control.Label
        ActivatetriangleCheckBox_2     matlab.ui.control.CheckBox
        UITable_2                      matlab.ui.control.Table
        LoadButton_10                  matlab.ui.control.Button
        CoaxialcableTab                matlab.ui.container.Tab
        InnerconductorradiusEditField_2  matlab.ui.control.NumericEditField
        InnerconductorradiusEditField_2Label  matlab.ui.control.Label
        ThicknessofouterringEditField_2  matlab.ui.control.NumericEditField
        ThicknessofouterringEditField_2Label  matlab.ui.control.Label
        ThicknessofdielectricmediaEditField_2  matlab.ui.control.NumericEditField
        ThicknessofdielectricmediaEditField_2Label  matlab.ui.control.Label
        ThicknessofinnerringEditField_2  matlab.ui.control.NumericEditField
        ThicknessofinnerringEditField_2Label  matlab.ui.control.Label
        RelativepermeabilityofdielectricEditField  matlab.ui.control.NumericEditField
        RelativepermeabilityofdielectricEditFieldLabel  matlab.ui.control.Label
        CurrentdensityofinnercableEditField  matlab.ui.control.NumericEditField
        CurrentdensityofinnercableEditFieldLabel  matlab.ui.control.Label
        RefreshButton_3                matlab.ui.control.Button
        DrawButton_5                   matlab.ui.control.Button
        ImportgeometryTab_2            matlab.ui.container.Tab
        FilenameEditField_2            matlab.ui.control.EditField
        FilenameEditField_2Label       matlab.ui.control.Label
        LoadButton_16                  matlab.ui.control.Button
        AssignEMpropertiesTab_2        matlab.ui.container.Tab
        ClearfieldsButton              matlab.ui.control.Button
        SolveButton_4                  matlab.ui.control.Button
        TabGroup8                      matlab.ui.container.TabGroup
        SourceTab_2                    matlab.ui.container.Tab
        ActivatesourceCheckBox         matlab.ui.control.CheckBox
        NumberoffacestogivecurrentdensityEditField  matlab.ui.control.NumericEditField
        NumberoffacestogivecurrentdensityEditFieldLabel  matlab.ui.control.Label
        LoadButton_11                  matlab.ui.control.Button
        UITable4_2                     matlab.ui.control.Table
        BoundaryconditionTab_2         matlab.ui.container.Tab
        ActivateboundaryconditionCheckBox_2  matlab.ui.control.CheckBox
        NumberofboundaryconditionEditField_2  matlab.ui.control.NumericEditField
        NumberofboundaryconditionEditField_2Label  matlab.ui.control.Label
        UITable5_2                     matlab.ui.control.Table
        LoadButton_12                  matlab.ui.control.Button
        FarfieldTab_2                  matlab.ui.container.Tab
        NumberofabsorbingboundaryconditionEditField_4  matlab.ui.control.NumericEditField
        NumberofabsorbingboundaryconditionEditField_4Label  matlab.ui.control.Label
        LoadButton_18                  matlab.ui.control.Button
        ActivateabsorbingboundaryconditionCheckBox_4  matlab.ui.control.CheckBox
        UITable7_4                     matlab.ui.control.Table
        RelativepermeabilityTab        matlab.ui.container.Tab
        TextArea                       matlab.ui.control.TextArea
        TextAreaLabel                  matlab.ui.control.Label
        NumberoffacestogiverelativepermeabilityEditField  matlab.ui.control.NumericEditField
        NumberoffacestogiverelativepermeabilityEditFieldLabel  matlab.ui.control.Label
        UITable6_2                     matlab.ui.control.Table
        LoadButton_14                  matlab.ui.control.Button
        RightPanel                     matlab.ui.container.Panel
        TabGroup2                      matlab.ui.container.TabGroup
        geometryTab                    matlab.ui.container.Tab
        UIAxes3                        matlab.ui.control.UIAxes
        meshTab                        matlab.ui.container.Tab
        UIAxes2                        matlab.ui.control.UIAxes
        potentialvectorfieldTab        matlab.ui.container.Tab
        InductanceperunitlengthHmEditField  matlab.ui.control.NumericEditField
        InductanceperunitlengthHmEditFieldLabel  matlab.ui.control.Label
        capacitanceperunitlengthFmEditField  matlab.ui.control.NumericEditField
        capacitanceperunitlengthFmEditFieldLabel  matlab.ui.control.Label
        UIAxes                         matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = private)
        emagmodel
        mesh_Hmax
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: TrianglenumberTextArea
        function TrianglenumberTextAreaValueChanged(app, event)
            value = app.TrianglenumberTextArea.Value;
        end

        % Button pushed function: LoadButton
        function LoadButtonPushed(app, event)
            s = app.TrianglenumberTextArea.Value;
            n = str2double(s);
            dt = zeros(n,6,"double");
            app.UITable.Data= dt;
        end

        % Display data changed function: UITable
        function UITableDisplayDataChanged(app, event)
            newDisplayData = app.UITable.DisplayData;
        end

        % Value changed function: capacitanceperunitlengthFmEditField
        function capacitanceperunitlengthFmEditFieldValueChanged(app, event)
            value = app.capacitanceperunitlengthFmEditField.Value;
        end

        % Button pushed function: DrawButton_4
        function DrawButton_4Pushed(app, event)
            h_plate = app.platethicknessmEditField.Value;
            x1 = 1;
            x2 = 1+app.widthofplatesmEditField.Value;
            y1 = 1;
            y2 = 1+app.distancebetweenplatesmEditField.Value;
            app.emagmodel = createpde(electromagnetic="electrostatic");
            SQ1 = [3; 4; x1; x2; x2; x1; y1-h_plate; y1-h_plate; y1; y1];
            SQ2 = [3; 4; x1; x2; x2; x1; y2; y2; y2+h_plate; y2+h_plate];
            SQ3 = [3; 4; x1; x2; x2; x1; y1+0.001; y1+0.001; y2-0.001; y2-0.001];
            SQ4 = [3; 4; 0; x2+1; x2+1; 0; 0; 0; x2+1; x2+1];
            gd = [SQ1 SQ2 SQ3 SQ4];
            sf = 'SQ4-(SQ3+SQ1+SQ2)+(SQ3+SQ1+SQ2)';
            ns = char('SQ1','SQ2','SQ3','SQ4');
            ns = ns';
            dl = decsg(gd,sf,ns);
            geometryFromEdges(app.emagmodel,dl);
            pdegplot(app.emagmodel,EdgeLabels="on",FaceLabels="on");
            hFig = gcf;
            hAx = gca;
            copyobj(allchild(hAx), app.UIAxes3);
            view(app.UIAxes3,2);
            close(hFig);

            app.emagmodel.VacuumPermittivity = 8.85*10^-12;
            electromagneticProperties(app.emagmodel,"RelativePermittivity",app.relativepermittivityofdielectricEditField.Value);
            electromagneticBC(app.emagmodel,"Edge",[2 3 5 6],"FarField","absorbing", "Thickness",0.2);
            
            % mesh
            app.mesh_Hmax = generateMesh(app.emagmodel,"Hmax",0.2);
            pdemesh(app.mesh_Hmax);
            hFig2 = gcf; 
            hAx2 = gca; 
            copyobj(allchild(hAx2), app.UIAxes2);
            view(app.UIAxes2,2)
            close(hFig2);

            %figure('Visible', 'off');
            p=app.chargedensityofplateQm2EditField.Value;
            area_of_one_rect = (x2-x1)*h_plate;
            electromagneticSource(app.emagmodel,"ChargeDensity",p,"Face",2);
            electromagneticSource(app.emagmodel,"ChargeDensity",-p,"Face",4);
            R = solve(app.emagmodel);
            
            pdeplot(app.mesh_Hmax,XYData=R.ElectricPotential, FlowData=[R.ElectricField.Ex, R.ElectricField.Ey],colormap="jet");
            hFig1 = gcf; 
            hAx1 = gca; 
            copyobj(allchild(hAx1), app.UIAxes);
            view(app.UIAxes,2)
            colorbar(app.UIAxes)
            colormap(app.UIAxes,"jet");
            close(hFig1);

            Ex = R.ElectricField.Ex;
            Ey = R.ElectricField.Ey;
            b = x1;
            a = x2;
            nodes_y_coordinates = app.mesh_Hmax.Nodes(2,:)';
            nodes_x_coordinates = app.mesh_Hmax.Nodes(1,:)';
            E_x_on_positivexaxis = Ey((nodes_x_coordinates<2+app.mesh_Hmax.MaxElementSize & nodes_x_coordinates>2-app.mesh_Hmax.MaxElementSize) & (nodes_y_coordinates>1 & nodes_y_coordinates<3));
            k = size(E_x_on_positivexaxis);
            integration_length = linspace(b,a,k(1));
            V_along_positive_x = (trapz(integration_length,E_x_on_positivexaxis));
            app.capacitanceperunitlengthFmEditField.Value=(area_of_one_rect*p)/V_along_positive_x;
        end

        % Value changed function: widthofplatesmEditField
        function widthofplatesmEditFieldValueChanged(app, event)
             widthofplates = app.widthofplatesmEditField.Value;
        end

        % Value changed function: distancebetweenplatesmEditField
        function distancebetweenplatesmEditFieldValueChanged(app, event)
            distancebetweenplates = app.distancebetweenplatesmEditField.Value;
        end

        % Value changed function: relativepermittivityofdielectricEditField
        function relativepermittivityofdielectricEditFieldValueChanged(app, event)
            relativepermittivityofdielectric = app.relativepermittivityofdielectricEditField.Value;
        end

        % Button pushed function: ClearallButton
        function ClearallButtonPushed(app, event)
            cla(app.UIAxes,'reset');
            cla(app.UIAxes2,'reset');
            cla(app.UIAxes3,'reset');
            app.capacitanceperunitlengthFmEditField.Value=0;
        end

        % Value changed function: platethicknessmEditField
        function platethicknessmEditFieldValueChanged(app, event)
            value = app.platethicknessmEditField.Value;
        end

        % Value changed function: chargedensityofplateQm2EditField
        function chargedensityofplateQm2EditFieldValueChanged(app, event)
            value = app.chargedensityofplateQm2EditField.Value;
        end

        % Button pushed function: DrawButton_3
        function DrawButton_3Pushed(app, event)
            app.emagmodel = createpde(electromagnetic="electrostatic");
            r1 = app.InnerconductorradiusEditField.Value;
            C1 = [1,0,0,r1]';
            r2 = app.InnerconductorradiusEditField.Value+app.ThicknessofinnerringEditField.Value;
            C2 = [1,0,0,r2]';
            r3 = app.InnerconductorradiusEditField.Value+app.ThicknessofinnerringEditField.Value+app.ThicknessofdielectricmediaEditField.Value;
            C3 = [1,0,0,r3]';
            r4 = app.InnerconductorradiusEditField.Value+app.ThicknessofinnerringEditField.Value+app.ThicknessofdielectricmediaEditField.Value+app.ThicknessofouterringEditField.Value;
            C4 = [1,0,0,r4]';
            figure('Visible', 'off');
            gm = [C1,C3,C4,C2];
            sf = '(C3+C4+C2)-C1';
            ns = char('C1','C3','C4','C2');
            ns = ns';
            g = decsg(gm,sf,ns);
            geometryFromEdges(app.emagmodel,g);
            pdegplot(app.emagmodel,EdgeLabels="on",FaceLabels="on")
            hFig = gcf;
            hAx = gca;
            copyobj(allchild(hAx), app.UIAxes3);
            view(app.UIAxes3,2);
            close(hFig);
            axis equal
            
            app.emagmodel.VacuumPermittivity = 8.85*10^-12;
            electromagneticProperties(app.emagmodel,"RelativePermittivity",1);
            
            epsulon_R_dielectric_medium = app.RelativepermittivityofdielectricEditField.Value;
            electromagneticProperties(app.emagmodel,RelativePermittivity=epsulon_R_dielectric_medium,Face=2);
            
            
            app.mesh_Hmax = generateMesh(app.emagmodel,"Hmax",0.2);
            pdemesh(app.mesh_Hmax);
            hFig2 = gcf; 
            hAx2 = gca; 
            copyobj(allchild(hAx2), app.UIAxes2);
            view(app.UIAxes2,2)
            close(hFig2);

            
            inner_area = pi*(r2^2-r1^2);
            outer_area = pi*(r4^2-r3^2);
            
            p_in = app.ChargedensityofinnercableEditField.Value;
            p_out = p_in*inner_area/outer_area;
            electromagneticSource(app.emagmodel,"ChargeDensity",-p_out,"Face",1);
            electromagneticSource(app.emagmodel,"ChargeDensity",p_in,"Face",3);
            
            R = solve(app.emagmodel);
            pdeplot(app.mesh_Hmax,XYData=R.ElectricPotential, FlowData=[R.ElectricField.Ex, R.ElectricField.Ey],colormap="jet");
            hFig1 = gcf; 
            hAx1 = gca; 
            copyobj(allchild(hAx1), app.UIAxes);
            view(app.UIAxes,2)
            colorbar(app.UIAxes)
            colormap(app.UIAxes,"jet");
            close(hFig1);
            
            Ex = R.ElectricField.Ex;
            Ey = R.ElectricField.Ey;
            b = r2;
            a = r3;
            
            nodes_y_coordinates = app.mesh_Hmax.Nodes(2,:)';
            nodes_x_coordinates = app.mesh_Hmax.Nodes(1,:)';
            E_x_on_positivexaxis = Ex((nodes_y_coordinates<app.mesh_Hmax.MaxElementSize & nodes_y_coordinates>-app.mesh_Hmax.MaxElementSize) & nodes_x_coordinates>0);
            k = size(E_x_on_positivexaxis);
            integration_length = linspace(b,a,k(1));
            V_along_positive_x = trapz(integration_length,E_x_on_positivexaxis);
            app.capacitanceperunitlengthFmEditField.Value = (p_in*inner_area)/V_along_positive_x;
        end

        % Value changed function: ChargedensityofinnercableEditField
        function ChargedensityofinnercableEditFieldValueChanged(app, event)
            value = app.ChargedensityofinnercableEditField.Value;
        end

        % Value changed function: RelativepermittivityofdielectricEditField
        function RelativepermittivityofdielectricEditFieldValueChanged(app, event)
            value = app.RelativepermittivityofdielectricEditField.Value;
        end

        % Value changed function: InnerconductorradiusEditField
        function InnerconductorradiusEditFieldValueChanged(app, event)
            value = app.InnerconductorradiusEditField.Value;
        end

        % Value changed function: ThicknessofinnerringEditField
        function ThicknessofinnerringEditFieldValueChanged(app, event)
            value = app.ThicknessofinnerringEditField.Value;
        end

        % Value changed function: ThicknessofdielectricmediaEditField
        function ThicknessofdielectricmediaEditFieldValueChanged(app, event)
            value = app.ThicknessofdielectricmediaEditField.Value;
        end

        % Value changed function: ThicknessofouterringEditField
        function ThicknessofouterringEditFieldValueChanged(app, event)
            value = app.ThicknessofouterringEditField.Value;
        end

        % Button pushed function: RefreshButton
        function RefreshButtonPushed(app, event)
            cla(app.UIAxes,'reset');
            cla(app.UIAxes2,'reset');
            cla(app.UIAxes3,'reset');
            app.capacitanceperunitlengthFmEditField.Value=0;
        end

        % Button pushed function: ClearsolutionkeepgeometryButton
        function ClearsolutionkeepgeometryButtonPushed(app, event)
            cla(app.UIAxes,'reset');
            cla(app.UIAxes2,'reset');
            app.capacitanceperunitlengthFmEditField.Value=0;
        end

        % Value changed function: RectanglenumberEditField
        function RectanglenumberEditFieldValueChanged(app, event)
            value = app.RectanglenumberEditField.Value;
        end

        % Button pushed function: LoadButton_2
        function LoadButton_2Pushed(app, event)
            s = app.RectanglenumberEditField.Value;
            dt = zeros(s,5,"double");
            app.UITable2.Data= dt;
        end

        % Button pushed function: DrawgeometryandmeshButton
        function DrawgeometryandmeshButtonPushed(app, event)
            app.emagmodel = createpde(electromagnetic="electrostatic");
            switch true
                % rectangle and circle case
                case app.ActivaterectangleCheckBox.Value==1 && app.ActivatecircleCheckBox.Value==1 && app.ActivatetriangleCheckBox.Value==0
                % create rectangle
                Edgelengthhorizontal_vector = app.UITable2.Data(:,1);
                Edgelengthvertical_vector = app.UITable2.Data(:,2);
                Centercoordinate_x_vector = app.UITable2.Data(:,3);
                Centercoordinate_y_vector = app.UITable2.Data(:,4);
                gdr=zeros(10,app.RectanglenumberEditField.Value);
                gdc=zeros(10,app.CirclenumberEditField.Value);
                Radius_vector = app.UITable3.Data(:,1);
                Centercoordinatec_x_vector = app.UITable3.Data(:,2);
                Centercoordinatec_y_vector = app.UITable3.Data(:,3);
                for i = 1:app.RectanglenumberEditField.Value
                    cx=Centercoordinate_x_vector(i,1);
                    cy=Centercoordinate_y_vector(i,1);
                    Lh=Edgelengthhorizontal_vector(i,1);
                    Lv=Edgelengthvertical_vector(i,1);
                    gdr(:,i) = [3; 4; cx-Lh/2; cx+Lh/2; cx+Lh/2; cx-Lh/2; cy+Lv/2; cy+Lv/2; cy-Lv/2; cy-Lv/2];
                end
    
                % create circle
                for j = 1:app.CirclenumberEditField.Value
                    ccx=Centercoordinatec_x_vector(j,1);
                    ccy=Centercoordinatec_y_vector(j,1);
                    radius=Radius_vector(j,1);
                    gdc(:,j) = [1;ccx;ccy;radius;0;0;0;0;0;0];
                end
                gd=[gdr gdc];

                % only rectangle case
                case app.ActivaterectangleCheckBox.Value==1 && app.ActivatecircleCheckBox.Value==0 && app.ActivatetriangleCheckBox.Value==0
                % create rectangle
                Edgelengthhorizontal_vector = app.UITable2.Data(:,1);
                Edgelengthvertical_vector = app.UITable2.Data(:,2);
                Centercoordinate_x_vector = app.UITable2.Data(:,3);
                Centercoordinate_y_vector = app.UITable2.Data(:,4);
                gdr=zeros(10,app.RectanglenumberEditField.Value);
                for i = 1:app.RectanglenumberEditField.Value
                    cx=Centercoordinate_x_vector(i,1);
                    cy=Centercoordinate_y_vector(i,1);
                    Lh=Edgelengthhorizontal_vector(i,1);
                    Lv=Edgelengthvertical_vector(i,1);
                    gdr(:,i) = [3; 4; cx-Lh/2; cx+Lh/2; cx+Lh/2; cx-Lh/2; cy+Lv/2; cy+Lv/2; cy-Lv/2; cy-Lv/2];
                end
                gd=gdr;

                % only circle case
                case app.ActivaterectangleCheckBox.Value==0 && app.ActivatecircleCheckBox.Value==1 && app.ActivatetriangleCheckBox.Value==0
                gdc=zeros(10,app.CirclenumberEditField.Value);
                Radius_vector = app.UITable3.Data(:,1);
                Centercoordinatec_x_vector = app.UITable3.Data(:,2);
                Centercoordinatec_y_vector = app.UITable3.Data(:,3);
                for j = 1:app.CirclenumberEditField.Value
                    ccx=Centercoordinatec_x_vector(j,1);
                    ccy=Centercoordinatec_y_vector(j,1);
                    radius=Radius_vector(j,1);
                    gdc(:,j) = [1;ccx;ccy;radius;0;0;0;0;0;0];
                end
                gd=gdc;

                % only triangle case
                case app.ActivaterectangleCheckBox.Value==0 && app.ActivatecircleCheckBox.Value==0 && app.ActivatetriangleCheckBox.Value==1
                % create triangle
                Vertice1X_vector = app.UITable.Data(:,1);
                Vertice1Y_vector = app.UITable.Data(:,2);
                Vertice2X_vector = app.UITable.Data(:,3);
                Vertice2Y_vector = app.UITable.Data(:,4);
                Vertice3X_vector = app.UITable.Data(:,5);
                Vertice3Y_vector = app.UITable.Data(:,6);
                n=str2double(app.TrianglenumberTextArea.Value);
                gdt=zeros(10,n);
                for i = 1:n
                    V1x=Vertice1X_vector(i,1);
                    V1y=Vertice1Y_vector(i,1);
                    V2x=Vertice2X_vector(i,1);
                    V2y=Vertice2Y_vector(i,1);
                    V3x=Vertice3X_vector(i,1);
                    V3y=Vertice3Y_vector(i,1);
                    gdt(:,i) = [2; 3; V1x; V2x; V3x; V1y; V2y; V3y; 0; 0];
                end
                gd=gdt;

                % triangle and rectangle case
                case app.ActivaterectangleCheckBox.Value==1 && app.ActivatecircleCheckBox.Value==0 && app.ActivatetriangleCheckBox.Value==1
                % create triangle
                Vertice1X_vector = app.UITable.Data(:,1);
                Vertice1Y_vector = app.UITable.Data(:,2);
                Vertice2X_vector = app.UITable.Data(:,3);
                Vertice2Y_vector = app.UITable.Data(:,4);
                Vertice3X_vector = app.UITable.Data(:,5);
                Vertice3Y_vector = app.UITable.Data(:,6);
                n=str2double(app.TrianglenumberTextArea.Value);
                gdt=zeros(10,n);
                for i = 1:n
                    V1x=Vertice1X_vector(i,1);
                    V1y=Vertice1Y_vector(i,1);
                    V2x=Vertice2X_vector(i,1);
                    V2y=Vertice2Y_vector(i,1);
                    V3x=Vertice3X_vector(i,1);
                    V3y=Vertice3Y_vector(i,1);
                    gdt(:,i) = [2; 3; V1x; V2x; V3x; V1y; V2y; V3y; 0; 0];
                end

                % create rectangle
                Edgelengthhorizontal_vector = app.UITable2.Data(:,1);
                Edgelengthvertical_vector = app.UITable2.Data(:,2);
                Centercoordinate_x_vector = app.UITable2.Data(:,3);
                Centercoordinate_y_vector = app.UITable2.Data(:,4);
                gdr=zeros(10,app.RectanglenumberEditField.Value);
                for i = 1:app.RectanglenumberEditField.Value
                    cx=Centercoordinate_x_vector(i,1);
                    cy=Centercoordinate_y_vector(i,1);
                    Lh=Edgelengthhorizontal_vector(i,1);
                    Lv=Edgelengthvertical_vector(i,1);
                    gdr(:,i) = [3; 4; cx-Lh/2; cx+Lh/2; cx+Lh/2; cx-Lh/2; cy+Lv/2; cy+Lv/2; cy-Lv/2; cy-Lv/2];
                end
                gd=[gdr gdt];

                % triangle and circle case
                case app.ActivaterectangleCheckBox.Value==0 && app.ActivatecircleCheckBox.Value==1 && app.ActivatetriangleCheckBox.Value==1
                
                % create triangle
                Vertice1X_vector = app.UITable.Data(:,1);
                Vertice1Y_vector = app.UITable.Data(:,2);
                Vertice2X_vector = app.UITable.Data(:,3);
                Vertice2Y_vector = app.UITable.Data(:,4);
                Vertice3X_vector = app.UITable.Data(:,5);
                Vertice3Y_vector = app.UITable.Data(:,6);
                n=str2double(app.TrianglenumberTextArea.Value);
                gdt=zeros(10,n);
                for i = 1:n
                    V1x=Vertice1X_vector(i,1);
                    V1y=Vertice1Y_vector(i,1);
                    V2x=Vertice2X_vector(i,1);
                    V2y=Vertice2Y_vector(i,1);
                    V3x=Vertice3X_vector(i,1);
                    V3y=Vertice3Y_vector(i,1);
                    gdt(:,i) = [2; 3; V1x; V2x; V3x; V1y; V2y; V3y; 0; 0];
                end
                
                % create circle
                gdc=zeros(10,app.CirclenumberEditField.Value);
                Radius_vector = app.UITable3.Data(:,1);
                Centercoordinatec_x_vector = app.UITable3.Data(:,2);
                Centercoordinatec_y_vector = app.UITable3.Data(:,3);
                for j = 1:app.CirclenumberEditField.Value
                    ccx=Centercoordinatec_x_vector(j,1);
                    ccy=Centercoordinatec_y_vector(j,1);
                    radius=Radius_vector(j,1);
                    gdc(:,j) = [1;ccx;ccy;radius;0;0;0;0;0;0];
                end
                gd=[gdc gdt];

                % triangle, rectangle and circle case
                case app.ActivaterectangleCheckBox.Value==1 && app.ActivatecircleCheckBox.Value==1 && app.ActivatetriangleCheckBox.Value==1
                
                % create triangle
                Vertice1X_vector = app.UITable.Data(:,1);
                Vertice1Y_vector = app.UITable.Data(:,2);
                Vertice2X_vector = app.UITable.Data(:,3);
                Vertice2Y_vector = app.UITable.Data(:,4);
                Vertice3X_vector = app.UITable.Data(:,5);
                Vertice3Y_vector = app.UITable.Data(:,6);
                n=str2double(app.TrianglenumberTextArea.Value);
                gdt=zeros(10,n);
                for i = 1:n
                    V1x=Vertice1X_vector(i,1);
                    V1y=Vertice1Y_vector(i,1);
                    V2x=Vertice2X_vector(i,1);
                    V2y=Vertice2Y_vector(i,1);
                    V3x=Vertice3X_vector(i,1);
                    V3y=Vertice3Y_vector(i,1);
                    gdt(:,i) = [2; 3; V1x; V2x; V3x; V1y; V2y; V3y; 0; 0];
                end
                
                % create circle
                gdc=zeros(10,app.CirclenumberEditField.Value);
                Radius_vector = app.UITable3.Data(:,1);
                Centercoordinatec_x_vector = app.UITable3.Data(:,2);
                Centercoordinatec_y_vector = app.UITable3.Data(:,3);
                for j = 1:app.CirclenumberEditField.Value
                    ccx=Centercoordinatec_x_vector(j,1);
                    ccy=Centercoordinatec_y_vector(j,1);
                    radius=Radius_vector(j,1);
                    gdc(:,j) = [1;ccx;ccy;radius;0;0;0;0;0;0];
                end

                % create rectangle
                Edgelengthhorizontal_vector = app.UITable2.Data(:,1);
                Edgelengthvertical_vector = app.UITable2.Data(:,2);
                Centercoordinate_x_vector = app.UITable2.Data(:,3);
                Centercoordinate_y_vector = app.UITable2.Data(:,4);
                gdr=zeros(10,app.RectanglenumberEditField.Value);
                for i = 1:app.RectanglenumberEditField.Value
                    cx=Centercoordinate_x_vector(i,1);
                    cy=Centercoordinate_y_vector(i,1);
                    Lh=Edgelengthhorizontal_vector(i,1);
                    Lv=Edgelengthvertical_vector(i,1);
                    gdr(:,i) = [3; 4; cx-Lh/2; cx+Lh/2; cx+Lh/2; cx-Lh/2; cy+Lv/2; cy+Lv/2; cy-Lv/2; cy-Lv/2];
                end
                gd=[gdr gdt gdc];

            end
            dl = decsg(gd);
            geometryFromEdges(app.emagmodel,dl);
            pdegplot(app.emagmodel,EdgeLabels="on",FaceLabels="on")
            hFig = gcf;
            hAx = gca;
            copyobj(allchild(hAx), app.UIAxes3);
            view(app.UIAxes3,2);
            close(hFig);
            app.mesh_Hmax = generateMesh(app.emagmodel,"Hmax",0.2);
            pdemesh(app.mesh_Hmax);
            hFig2 = gcf; 
            hAx2 = gca; 
            copyobj(allchild(hAx2), app.UIAxes2);
            view(app.UIAxes2,2)
            close(hFig2);
        end

        % Display data changed function: UITable2
        function UITable2DisplayDataChanged(app, event)
            newDisplayData = app.UITable2.DisplayData;
        end

        % Value changed function: CirclenumberEditField
        function CirclenumberEditFieldValueChanged(app, event)
            value = app.CirclenumberEditField.Value;
            s = app.CirclenumberEditField.Value;
            dt = zeros(s,3,"double");
            app.UITable3.Data= dt;
        end

        % Value changed function: ActivaterectangleCheckBox
        function ActivaterectangleCheckBoxValueChanged(app, event)
            value = app.ActivaterectangleCheckBox.Value;
        end

        % Value changed function: ActivatecircleCheckBox
        function ActivatecircleCheckBoxValueChanged(app, event)
            value = app.ActivatecircleCheckBox.Value;
        end

        % Value changed function: ActivatetriangleCheckBox
        function ActivatetriangleCheckBoxValueChanged(app, event)
            value = app.ActivatetriangleCheckBox.Value;
        end

        % Button pushed function: RefreshButton_2
        function RefreshButton_2Pushed(app, event)
            cla(app.UIAxes,'reset');
            cla(app.UIAxes2,'reset');
            cla(app.UIAxes3,'reset');
            app.RectanglenumberEditField.Value=0;
            app.CirclenumberEditField.Value=0;
            app.TrianglenumberTextArea.Value='';
            app.ActivatetriangleCheckBox.Value=0;
            app.ActivatecircleCheckBox.Value=0;
            app.ActivaterectangleCheckBox.Value=0;
            app.UITable.Data = {};
            app.UITable2.Data = {};
            app.UITable3.Data = {};
        end

        % Value changed function: NumberoffacestogivechargedensityEditField
        function NumberoffacestogivechargedensityEditFieldValueChanged(app, event)
            value = app.NumberoffacestogivechargedensityEditField.Value;
        end

        % Display data changed function: UITable4
        function UITable4DisplayDataChanged(app, event)
            newDisplayData = app.UITable4.DisplayData;
        end

        % Button pushed function: LoadButton_4
        function LoadButton_4Pushed(app, event)
            s = app.NumberoffacestogivechargedensityEditField.Value;
            dt = zeros(s,2,"double");
            app.UITable4.Data= dt;
        end

        % Button pushed function: SolveButton_2
        function SolveButton_2Pushed(app, event)
            if app.ActivatesourceCheckBox_2.Value==1
            % charge density
            Face_vector_src = app.UITable4.Data(:,1);
            Chargedensity_vector = app.UITable4.Data(:,2);
            for i=1:app.NumberoffacestogivechargedensityEditField.Value
                Face_src=Face_vector_src(i,1);
                chargedensity=Chargedensity_vector(i,1);
                electromagneticSource(app.emagmodel,"ChargeDensity",chargedensity,"Face",Face_src);
            end
            end
            
            if app.ActivateboundaryconditionCheckBox.Value==1
            % boundary condition
            Edge_vector_bc = app.UITable5.Data(:,1);
            voltage_bc_vector = app.UITable5.Data(:,2);
            for j=1:app.NumberofboundaryconditionEditField.Value
                edge=Edge_vector_bc(j,1);
                bc=voltage_bc_vector(j,1);
                electromagneticBC(app.emagmodel,Voltage=bc,Edge=edge);
            end
            end

            % relative permittivity
            Face_vector_rp = app.UITable6.Data(:,1);
            relativepermittivity_vector = app.UITable6.Data(:,2);
            app.emagmodel.VacuumPermittivity = 8.85*10^-12;
            electromagneticProperties(app.emagmodel,"RelativePermittivity",1);
            for k=1:app.NumberoffacestogiverelativepermittivityEditField.Value
                relativepermittivity=relativepermittivity_vector(k,1);
                Face_rp=Face_vector_rp(k,1);
                electromagneticProperties(app.emagmodel,RelativePermittivity=relativepermittivity,Face=Face_rp);
            end

            if app.ActivateabsorbingboundaryconditionCheckBox_3.Value==1
                absbc_edge_vector = app.UITable7_3.Data(:,1);
                thickness_vector = app.UITable7_3.Data(:,2);
                for l=1:app.NumberofabsorbingboundaryconditionEditField_3.Value
                    absbc_edge = absbc_edge_vector(l,1);
                    thicknessof_edge = thickness_vector(l,1);
                    electromagneticBC(app.emagmodel,"Edge",absbc_edge,"FarField","absorbing", "Thickness",thicknessof_edge);
                end
            end
            R = solve(app.emagmodel);
            pdeplot(app.mesh_Hmax,XYData=R.ElectricPotential, FlowData=[R.ElectricField.Ex, R.ElectricField.Ey],colormap="jet");
            hFig1 = gcf; 
            hAx1 = gca; 
            copyobj(allchild(hAx1), app.UIAxes);
            view(app.UIAxes,2)
            colorbar(app.UIAxes)
            colormap(app.UIAxes,"jet");
            close(hFig1);

            Vdiff = max(R.ElectricPotential)-min(R.ElectricPotential);
            app.capacitanceperunitlengthFmEditField.Value = 1/Vdiff;
            
            %{
            geomat = [max(app.UITable.Data(:,2)) max(app.UITable.Data(:,1)) max(app.UITable2.Data(:,2)) max(app.UITable2.Data(:,1)) max(app.UITable3.Data(:,2)) max(app.UITable3.Data(:,1))];
            geomat(isnan(geomat))=0;
            vlin = linspace(-max(geomat),max(geomat),51);
            [X,Y] = meshgrid(vlin);

            Eintrp = interpolateMagneticField(R,X,Y);
            EintrpX = reshape(Eintrp.Ex,size(X));
            EintrpY = reshape(Eintrp.Ey,size(Y));
            quiver(X,Y,EintrpX,EintrpY,"Color","red")
            hFig2 = gcf; 
            hAx2 = gca; 
            copyobj(allchild(hAx2), app.UIAxes4);
            view(app.UIAxes4,2)
            colorbar(app.UIAxes4)
            colormap(app.UIAxes4,"jet");
            close(hFig2);
            %}
        end

        % Value changed function: NumberofboundaryconditionEditField
        function NumberofboundaryconditionEditFieldValueChanged(app, event)
            value = app.NumberofboundaryconditionEditField.Value;
        end

        % Button pushed function: LoadButton_5
        function LoadButton_5Pushed(app, event)
            s = app.NumberofboundaryconditionEditField.Value;
            dt = zeros(s,2,"double");
            app.UITable5.Data= dt;
        end

        % Display data changed function: UITable5
        function UITable5DisplayDataChanged(app, event)
            newDisplayData = app.UITable5.DisplayData;
        end

        % Value changed function: 
        % NumberoffacestogiverelativepermittivityEditField
        function NumberoffacestogiverelativepermittivityEditFieldValueChanged(app, event)
            value = app.NumberoffacestogiverelativepermittivityEditField.Value;
        end

        % Button pushed function: LoadButton_6
        function LoadButton_6Pushed(app, event)
            s = app.NumberoffacestogiverelativepermittivityEditField.Value;
            dt = zeros(s,2,"double");
            app.UITable6.Data= dt;
        end

        % Display data changed function: UITable6
        function UITable6DisplayDataChanged(app, event)
            newDisplayData = app.UITable6.DisplayData;
        end

        % Button pushed function: LoadButton_17
        function LoadButton_7Pushed(app, event)
            s = app.NumberofabsorbingboundaryconditionEditField_3.Value;
            dt = zeros(s,2,"double");
            app.UITable7_3.Data= dt;
        end

        % Value changed function: ActivaterectangleCheckBox_2
        function ActivaterectangleCheckBox_2ValueChanged(app, event)
            value = app.ActivaterectangleCheckBox_2.Value;
        end

        % Value changed function: RectanglenumberEditField_2
        function RectanglenumberEditField_2ValueChanged(app, event)
            value = app.RectanglenumberEditField_2.Value;
            
        end

        % Button pushed function: LoadButton_8
        function LoadButton_8Pushed(app, event)
            s = app.RectanglenumberEditField_2.Value;
            dt = zeros(s,5,"double");
            app.UITable2_2.Data= dt;
        end

        % Display data changed function: UITable2_2
        function UITable2_2DisplayDataChanged(app, event)
            newDisplayData = app.UITable2_2.DisplayData;
            
        end

        % Value changed function: ActivatecircleCheckBox_2
        function ActivatecircleCheckBox_2ValueChanged(app, event)
            value = app.ActivatecircleCheckBox_2.Value;
            
        end

        % Value changed function: CirclenumberEditField_2
        function CirclenumberEditField_2ValueChanged(app, event)
            value = app.CirclenumberEditField_2.Value;
            
        end

        % Button pushed function: LoadButton_9
        function LoadButton_9Pushed(app, event)
            s = app.CirclenumberEditField_2.Value;
            dt = zeros(s,3,"double");
            app.UITable3_2.Data= dt;
        end

        % Display data changed function: UITable3_2
        function UITable3_2DisplayDataChanged(app, event)
            newDisplayData = app.UITable3_2.DisplayData;
            
        end

        % Button pushed function: LoadButton_3
        function LoadButton_3Pushed(app, event)
            s = app.CirclenumberEditField.Value;
            dt = zeros(s,3,"double");
            app.UITable3.Data= dt;
        end

        % Value changed function: ActivatetriangleCheckBox_2
        function ActivatetriangleCheckBox_2ValueChanged(app, event)
            value = app.ActivatetriangleCheckBox_2.Value;
        end

        % Value changed function: TrianglenumberEditField
        function TrianglenumberEditFieldValueChanged(app, event)
            value = app.TrianglenumberEditField.Value;
        end

        % Button pushed function: LoadButton_10
        function LoadButton_10Pushed(app, event)
            s = app.TrianglenumberEditField.Value;
            dt = zeros(s,6,"double");
            app.UITable_2.Data= dt;
        end

        % Display data changed function: UITable_2
        function UITable_2DisplayDataChanged(app, event)
            newDisplayData = app.UITable_2.DisplayData;
        end

        % Value changed function: InnerconductorradiusEditField_2
        function InnerconductorradiusEditField_2ValueChanged(app, event)
            value = app.InnerconductorradiusEditField_2.Value;
            
        end

        % Value changed function: ThicknessofinnerringEditField_2
        function ThicknessofinnerringEditField_2ValueChanged(app, event)
            value = app.ThicknessofinnerringEditField_2.Value;
            
        end

        % Value changed function: ThicknessofdielectricmediaEditField_2
        function ThicknessofdielectricmediaEditField_2ValueChanged(app, event)
            value = app.ThicknessofdielectricmediaEditField_2.Value;
            
        end

        % Value changed function: ThicknessofouterringEditField_2
        function ThicknessofouterringEditField_2ValueChanged(app, event)
            value = app.ThicknessofouterringEditField_2.Value;
            
        end

        % Value changed function: RelativepermeabilityofdielectricEditField
        function RelativepermeabilityofdielectricEditFieldValueChanged(app, event)
            value = app.RelativepermeabilityofdielectricEditField.Value;
            
        end

        % Value changed function: CurrentdensityofinnercableEditField
        function CurrentdensityofinnercableEditFieldValueChanged(app, event)
            value = app.CurrentdensityofinnercableEditField.Value;
            
        end

        % Button pushed function: DrawButton_5
        function DrawButton_5Pushed(app, event)
            app.emagmodel = createpde(electromagnetic="magnetostatic");
            r1 = app.InnerconductorradiusEditField.Value;
            C1 = [1,0,0,r1]';
            r2 = app.InnerconductorradiusEditField.Value+app.ThicknessofinnerringEditField.Value;
            C2 = [1,0,0,r2]';
            r3 = app.InnerconductorradiusEditField.Value+app.ThicknessofinnerringEditField.Value+app.ThicknessofdielectricmediaEditField.Value;
            C3 = [1,0,0,r3]';
            r4 = app.InnerconductorradiusEditField.Value+app.ThicknessofinnerringEditField.Value+app.ThicknessofdielectricmediaEditField.Value+app.ThicknessofouterringEditField.Value;
            C4 = [1,0,0,r4]';
            figure('Visible', 'off');
            gm = [C1,C3,C4,C2];
            sf = '(C3+C4+C2)-C1';
            ns = char('C1','C3','C4','C2');
            ns = ns';
            g = decsg(gm,sf,ns);
            geometryFromEdges(app.emagmodel,g);
            pdegplot(app.emagmodel,EdgeLabels="on",FaceLabels="on")
            hFig = gcf;
            hAx = gca;
            copyobj(allchild(hAx), app.UIAxes3);
            view(app.UIAxes3,2);
            close(hFig);
            axis equal
            app.emagmodel.VacuumPermeability = 4*pi*10^-7;
            electromagneticProperties(app.emagmodel,"RelativePermeability",1);
            
            mu_R_dielectric_medium = app.RelativepermeabilityofdielectricEditField.Value;
            electromagneticProperties(app.emagmodel,RelativePermeability=mu_R_dielectric_medium,Face=2);
            
            
            app.mesh_Hmax = generateMesh(app.emagmodel,"Hmax",0.2);
            pdemesh(app.mesh_Hmax);
            hFig2 = gcf; 
            hAx2 = gca; 
            copyobj(allchild(hAx2), app.UIAxes2);
            view(app.UIAxes2,2)
            close(hFig2);

            inner_area = pi*(r2^2-r1^2);
            outer_area = pi*(r4^2-r3^2);
            j_in = app.CurrentdensityofinnercableEditField.Value;
            j_out = j_in*inner_area/outer_area;
            electromagneticSource(app.emagmodel,"CurrentDensity",-j_out,"Face",1);
            electromagneticSource(app.emagmodel,"CurrentDensity",j_in,"Face",3);
            R = solve(app.emagmodel);
            pdeplot(app.mesh_Hmax,XYData=R.MagneticPotential,FlowData=[R.MagneticField.Hx, R.MagneticField.Hy], ColorMap="hot");
            hFig1 = gcf; 
            hAx1 = gca; 
            copyobj(allchild(hAx1), app.UIAxes);
            view(app.UIAxes,2)
            colorbar(app.UIAxes)
            colormap(app.UIAxes,"hot");
            close(hFig1);
            
            BBx = R.MagneticFluxDensity.Bx;
            BBy = R.MagneticFluxDensity.By;
            b = r2;
            a = r3;

            mesh_y = app.mesh_Hmax.Nodes(2,:)';
            mesh_x = app.mesh_Hmax.Nodes(1,:)';
            Bthrough_x_on_positivexaxis = BBy((mesh_y<app.mesh_Hmax.MaxElementSize & mesh_y>-app.mesh_Hmax.MaxElementSize) & mesh_x>0);
            k = size(Bthrough_x_on_positivexaxis);
            integration_length = linspace(b,a,k(1));
            app.InductanceperunitlengthHmEditField.Value = trapz(integration_length,Bthrough_x_on_positivexaxis);
            
        end

        % Button pushed function: RefreshButton_3
        function RefreshButton_3Pushed(app, event)
            cla(app.UIAxes,'reset');
            cla(app.UIAxes2,'reset');
            cla(app.UIAxes3,'reset');
            app.InductanceperunitlengthHmEditField.Value;
        end

        % Value changed function: InductanceperunitlengthHmEditField
        function InductanceperunitlengthHmEditFieldValueChanged(app, event)
            value = app.InductanceperunitlengthHmEditField.Value;
            
        end

        % Button pushed function: DrawgeometryandmeshButton_2
        function DrawgeometryandmeshButton_2Pushed(app, event)
            app.emagmodel = createpde(electromagnetic="magnetostatic");
            switch true
                % rectangle and circle case
                case app.ActivaterectangleCheckBox_2.Value==1 && app.ActivatecircleCheckBox_2.Value==1 && app.ActivatetriangleCheckBox_2.Value==0
                % create rectangle
                Edgelengthhorizontal_vector = app.UITable2_2.Data(:,1);
                Edgelengthvertical_vector = app.UITable2_2.Data(:,2);
                Centercoordinate_x_vector = app.UITable2_2.Data(:,3);
                Centercoordinate_y_vector = app.UITable2_2.Data(:,4);
                gdr=zeros(10,app.RectanglenumberEditField_2.Value);
                gdc=zeros(10,app.CirclenumberEditField_2.Value);
                Radius_vector = app.UITable3_2.Data(:,1);
                Centercoordinatec_x_vector = app.UITable3_2.Data(:,2);
                Centercoordinatec_y_vector = app.UITable3_2.Data(:,3);
                for i = 1:app.RectanglenumberEditField_2.Value
                    cx=Centercoordinate_x_vector(i,1);
                    cy=Centercoordinate_y_vector(i,1);
                    Lh=Edgelengthhorizontal_vector(i,1);
                    Lv=Edgelengthvertical_vector(i,1);
                    gdr(:,i) = [3; 4; cx-Lh/2; cx+Lh/2; cx+Lh/2; cx-Lh/2; cy+Lv/2; cy+Lv/2; cy-Lv/2; cy-Lv/2];
                end
    
                % create circle
                for j = 1:app.CirclenumberEditField_2.Value
                    ccx=Centercoordinatec_x_vector(j,1);
                    ccy=Centercoordinatec_y_vector(j,1);
                    radius=Radius_vector(j,1);
                    gdc(:,j) = [1;ccx;ccy;radius;0;0;0;0;0;0];
                end
                gd=[gdr gdc];

                % only rectangle case
                case app.ActivaterectangleCheckBox_2.Value==1 && app.ActivatecircleCheckBox_2.Value==0 && app.ActivatetriangleCheckBox_2.Value==0
                % create rectangle
                Edgelengthhorizontal_vector = app.UITable2_2.Data(:,1);
                Edgelengthvertical_vector = app.UITable2_2.Data(:,2);
                Centercoordinate_x_vector = app.UITable2_2.Data(:,3);
                Centercoordinate_y_vector = app.UITable2_2.Data(:,4);
                gdr=zeros(10,app.RectanglenumberEditField_2.Value);
                for i = 1:app.RectanglenumberEditField_2.Value
                    cx=Centercoordinate_x_vector(i,1);
                    cy=Centercoordinate_y_vector(i,1);
                    Lh=Edgelengthhorizontal_vector(i,1);
                    Lv=Edgelengthvertical_vector(i,1);
                    gdr(:,i) = [3; 4; cx-Lh/2; cx+Lh/2; cx+Lh/2; cx-Lh/2; cy+Lv/2; cy+Lv/2; cy-Lv/2; cy-Lv/2];
                end
                gd=gdr;

                % only circle case
                case app.ActivaterectangleCheckBox_2.Value==0 && app.ActivatecircleCheckBox_2.Value==1 && app.ActivatetriangleCheckBox_2.Value==0
                gdc=zeros(10,app.CirclenumberEditField_2.Value);
                Radius_vector = app.UITable3_2.Data(:,1);
                Centercoordinatec_x_vector = app.UITable3_2.Data(:,2);
                Centercoordinatec_y_vector = app.UITable3_2.Data(:,3);
                for j = 1:app.CirclenumberEditField_2.Value
                    ccx=Centercoordinatec_x_vector(j,1);
                    ccy=Centercoordinatec_y_vector(j,1);
                    radius=Radius_vector(j,1);
                    gdc(:,j) = [1;ccx;ccy;radius;0;0;0;0;0;0];
                end
                gd=gdc;

                % only triangle case
                case app.ActivaterectangleCheckBox_2.Value==0 && app.ActivatecircleCheckBox_2.Value==0 && app.ActivatetriangleCheckBox_2.Value==1
                % create triangle
                Vertice1X_vector = app.UITable_2.Data(:,1);
                Vertice1Y_vector = app.UITable_2.Data(:,2);
                Vertice2X_vector = app.UITable_2.Data(:,3);
                Vertice2Y_vector = app.UITable_2.Data(:,4);
                Vertice3X_vector = app.UITable_2.Data(:,5);
                Vertice3Y_vector = app.UITable_2.Data(:,6);
                gdt=zeros(10,app.TrianglenumberEditField.Value);
                for i = 1:app.TrianglenumberEditField.Value
                    V1x=Vertice1X_vector(i,1);
                    V1y=Vertice1Y_vector(i,1);
                    V2x=Vertice2X_vector(i,1);
                    V2y=Vertice2Y_vector(i,1);
                    V3x=Vertice3X_vector(i,1);
                    V3y=Vertice3Y_vector(i,1);
                    gdt(:,i) = [2; 3; V1x; V2x; V3x; V1y; V2y; V3y; 0; 0];
                end
                gd=gdt;

                % triangle and rectangle case
                case app.ActivaterectangleCheckBox_2.Value==1 && app.ActivatecircleCheckBox_2.Value==0 && app.ActivatetriangleCheckBox_2.Value==1
                % create triangle
                Vertice1X_vector = app.UITable_2.Data(:,1);
                Vertice1Y_vector = app.UITable_2.Data(:,2);
                Vertice2X_vector = app.UITable_2.Data(:,3);
                Vertice2Y_vector = app.UITable_2.Data(:,4);
                Vertice3X_vector = app.UITable_2.Data(:,5);
                Vertice3Y_vector = app.UITable_2.Data(:,6);
                gdt=zeros(10,app.TrianglenumberEditField.Value);
                for i = 1:app.TrianglenumberEditField.Value
                    V1x=Vertice1X_vector(i,1);
                    V1y=Vertice1Y_vector(i,1);
                    V2x=Vertice2X_vector(i,1);
                    V2y=Vertice2Y_vector(i,1);
                    V3x=Vertice3X_vector(i,1);
                    V3y=Vertice3Y_vector(i,1);
                    gdt(:,i) = [2; 3; V1x; V2x; V3x; V1y; V2y; V3y; 0; 0];
                end

                % create rectangle
                Edgelengthhorizontal_vector = app.UITable2_2.Data(:,1);
                Edgelengthvertical_vector = app.UITable2_2.Data(:,2);
                Centercoordinate_x_vector = app.UITable2_2.Data(:,3);
                Centercoordinate_y_vector = app.UITable2_2.Data(:,4);
                gdr=zeros(10,app.RectanglenumberEditField.Value);
                for i = 1:app.RectanglenumberEditField.Value
                    cx=Centercoordinate_x_vector(i,1);
                    cy=Centercoordinate_y_vector(i,1);
                    Lh=Edgelengthhorizontal_vector(i,1);
                    Lv=Edgelengthvertical_vector(i,1);
                    gdr(:,i) = [3; 4; cx-Lh/2; cx+Lh/2; cx+Lh/2; cx-Lh/2; cy+Lv/2; cy+Lv/2; cy-Lv/2; cy-Lv/2];
                end
                gd=[gdr gdt];

                % triangle and circle case
                case app.ActivaterectangleCheckBox_2.Value==0 && app.ActivatecircleCheckBox_2.Value==1 && app.ActivatetriangleCheckBox_2.Value==1
                
                % create triangle
                Vertice1X_vector = app.UITable_2.Data(:,1);
                Vertice1Y_vector = app.UITable_2.Data(:,2);
                Vertice2X_vector = app.UITable_2.Data(:,3);
                Vertice2Y_vector = app.UITable_2.Data(:,4);
                Vertice3X_vector = app.UITable_2.Data(:,5);
                Vertice3Y_vector = app.UITable_2.Data(:,6);
                gdt=zeros(10,app.TrianglenumberEditField.Value);
                for i = 1:app.TrianglenumberEditField.Value
                    V1x=Vertice1X_vector(i,1);
                    V1y=Vertice1Y_vector(i,1);
                    V2x=Vertice2X_vector(i,1);
                    V2y=Vertice2Y_vector(i,1);
                    V3x=Vertice3X_vector(i,1);
                    V3y=Vertice3Y_vector(i,1);
                    gdt(:,i) = [2; 3; V1x; V2x; V3x; V1y; V2y; V3y; 0; 0];
                end
                
                % create circle
                gdc=zeros(10,app.CirclenumberEditField_2.Value);
                Radius_vector = app.UITable3.Data(:,1);
                Centercoordinatec_x_vector = app.UITable3_2.Data(:,2);
                Centercoordinatec_y_vector = app.UITable3_2.Data(:,3);
                for j = 1:app.CirclenumberEditField_2.Value
                    ccx=Centercoordinatec_x_vector(j,1);
                    ccy=Centercoordinatec_y_vector(j,1);
                    radius=Radius_vector(j,1);
                    gdc(:,j) = [1;ccx;ccy;radius;0;0;0;0;0;0];
                end
                gd=[gdc gdt];

                % triangle, rectangle and circle case
                case app.ActivaterectangleCheckBox_2.Value==1 && app.ActivatecircleCheckBox_2.Value==1 && app.ActivatetriangleCheckBox_2.Value==1
                
                % create triangle
                Vertice1X_vector = app.UITable_2.Data(:,1);
                Vertice1Y_vector = app.UITable_2.Data(:,2);
                Vertice2X_vector = app.UITable_2.Data(:,3);
                Vertice2Y_vector = app.UITable_2.Data(:,4);
                Vertice3X_vector = app.UITable_2.Data(:,5);
                Vertice3Y_vector = app.UITable_2.Data(:,6);
                gdt=zeros(10,app.TrianglenumberEditField.Value);
                for i = 1:app.TrianglenumberEditField.Value
                    V1x=Vertice1X_vector(i,1);
                    V1y=Vertice1Y_vector(i,1);
                    V2x=Vertice2X_vector(i,1);
                    V2y=Vertice2Y_vector(i,1);
                    V3x=Vertice3X_vector(i,1);
                    V3y=Vertice3Y_vector(i,1);
                    gdt(:,i) = [2; 3; V1x; V2x; V3x; V1y; V2y; V3y; 0; 0];
                end
                
                % create circle
                gdc=zeros(10,app.CirclenumberEditField_2.Value);
                Radius_vector = app.UITable3.Data(:,1);
                Centercoordinatec_x_vector = app.UITable3_2.Data(:,2);
                Centercoordinatec_y_vector = app.UITable3_2.Data(:,3);
                for j = 1:app.CirclenumberEditField_2.Value
                    ccx=Centercoordinatec_x_vector(j,1);
                    ccy=Centercoordinatec_y_vector(j,1);
                    radius=Radius_vector(j,1);
                    gdc(:,j) = [1;ccx;ccy;radius;0;0;0;0;0;0];
                end

                % create rectangle
                Edgelengthhorizontal_vector = app.UITable2_2.Data(:,1);
                Edgelengthvertical_vector = app.UITable2_2.Data(:,2);
                Centercoordinate_x_vector = app.UITable2_2.Data(:,3);
                Centercoordinate_y_vector = app.UITable2_2.Data(:,4);
                gdr=zeros(10,app.RectanglenumberEditField.Value);
                for i = 1:app.RectanglenumberEditField.Value
                    cx=Centercoordinate_x_vector(i,1);
                    cy=Centercoordinate_y_vector(i,1);
                    Lh=Edgelengthhorizontal_vector(i,1);
                    Lv=Edgelengthvertical_vector(i,1);
                    gdr(:,i) = [3; 4; cx-Lh/2; cx+Lh/2; cx+Lh/2; cx-Lh/2; cy+Lv/2; cy+Lv/2; cy-Lv/2; cy-Lv/2];
                end
                gd=[gdr gdt gdc];

            end
            dl = decsg(gd);
            geometryFromEdges(app.emagmodel,dl);
            pdegplot(app.emagmodel,EdgeLabels="on",FaceLabels="on")
            hFig = gcf;
            hAx = gca;
            copyobj(allchild(hAx), app.UIAxes3);
            view(app.UIAxes3,2);
            close(hFig);
            app.mesh_Hmax = generateMesh(app.emagmodel,"Hmax",0.2);
            pdemesh(app.mesh_Hmax);
            hFig2 = gcf; 
            hAx2 = gca; 
            copyobj(allchild(hAx2), app.UIAxes2);
            view(app.UIAxes2,2)
            close(hFig2);
        end

        % Button pushed function: RefreshButton_4
        function RefreshButton_4Pushed(app, event)
            cla(app.UIAxes,'reset');
            cla(app.UIAxes2,'reset');
            cla(app.UIAxes3,'reset');
            app.RectanglenumberEditField_2.Value=0;
            app.CirclenumberEditField_2.Value=0;
            app.TrianglenumberEditField.Value=0;
            app.ActivatetriangleCheckBox_2.Value=0;
            app.ActivatecircleCheckBox_2.Value=0;
            app.ActivaterectangleCheckBox_2.Value=0;
            app.UITable_2.Data = {};
            app.UITable2_2.Data = {};
            app.UITable3_2.Data = {};
        end

        % Value changed function: 
        % NumberoffacestogivecurrentdensityEditField
        function NumberoffacestogivecurrentdensityEditFieldValueChanged(app, event)
            value = app.NumberoffacestogivecurrentdensityEditField.Value;
        end

        % Button pushed function: LoadButton_11
        function LoadButton_11Pushed(app, event)
            s = app.NumberoffacestogivecurrentdensityEditField.Value;
            dt = zeros(s,2,"double");
            app.UITable4_2.Data= dt;
        end

        % Display data changed function: UITable4_2
        function UITable4_2DisplayDataChanged(app, event)
            newDisplayData = app.UITable4_2.DisplayData;
        end

        % Value changed function: NumberofboundaryconditionEditField_2
        function NumberofboundaryconditionEditField_2ValueChanged(app, event)
            value = app.NumberofboundaryconditionEditField_2.Value;
            
        end

        % Button pushed function: LoadButton_12
        function LoadButton_12Pushed(app, event)
            s = app.NumberofboundaryconditionEditField_2.Value;
            dt = zeros(s,2,"double");
            app.UITable5_2.Data= dt;
        end

        % Display data changed function: UITable5_2
        function UITable5_2DisplayDataChanged(app, event)
            newDisplayData = app.UITable5_2.DisplayData;
            
        end

        % Callback function
        function LoadButton_13Pushed(app, event)
            s = app.NumberofabsorbingboundaryconditionEditField_2.Value;
            dt = zeros(s,2,"double");
            app.UITable7_2.Data= dt;
        end

        % Value changed function: 
        % NumberoffacestogiverelativepermeabilityEditField
        function NumberoffacestogiverelativepermeabilityEditFieldValueChanged(app, event)
            value = app.NumberoffacestogiverelativepermeabilityEditField.Value;
            
        end

        % Button pushed function: LoadButton_14
        function LoadButton_14Pushed(app, event)
            s = app.NumberoffacestogiverelativepermeabilityEditField.Value;
            dt = zeros(s,2,"double");
            app.UITable6_2.Data= dt;
        end

        % Display data changed function: UITable6_2
        function UITable6_2DisplayDataChanged(app, event)
            newDisplayData = app.UITable6_2.DisplayData;
            
        end

        % Button pushed function: SolveButton_4
        function SolveButton_4Pushed(app, event)
            if app.ActivatesourceCheckBox.Value==1
            % current density
            Face_vector_src = app.UITable4_2.Data(:,1);
            Currentdensity_vector = app.UITable4_2.Data(:,2);
            for i=1:app.NumberoffacestogivecurrentdensityEditField.Value
                Face_src=Face_vector_src(i,1);
                currentdensity=Currentdensity_vector(i,1);
                electromagneticSource(app.emagmodel,"CurrentDensity",currentdensity,"Face",Face_src);
            end
            end
            
            if app.ActivateboundaryconditionCheckBox_2.Value==1
            % boundary condition
            Edge_vector_bc = app.UITable5_2.Data(:,1);
            magneticpot_bc_vector = app.UITable5_2.Data(:,2);
            for j=1:app.NumberofboundaryconditionEditField.Value
                edge=Edge_vector_bc(j,1);
                bc=magneticpot_bc_vector(j,1);
                electromagneticBC(app.emagmodel,MagneticPotential=bc,Edge=edge);
            end
            end
            
            % relative permeability
            Face_vector_rp = app.UITable6_2.Data(:,1);
            relativepermeability_vector = app.UITable6_2.Data(:,2);
            app.emagmodel.VacuumPermeability = 4*pi*10^-7;
            electromagneticProperties(app.emagmodel,"RelativePermeability",1);
            for k=1:app.NumberoffacestogiverelativepermeabilityEditField.Value
                relativepermeability=relativepermeability_vector(k,1);
                Face_rp=Face_vector_rp(k,1);
                electromagneticProperties(app.emagmodel,RelativePermeability=relativepermeability,Face=Face_rp);
            end

            if app.ActivateabsorbingboundaryconditionCheckBox_4.Value==1
                absbc_edge_vector = app.UITable7_4.Data(:,1);
                thickness_vector = app.UITable7_4.Data(:,2);
                for l=1:app.NumberofabsorbingboundaryconditionEditField_4.Value
                    absbc_edge = absbc_edge_vector(l,1);
                    thicknessof_edge = thickness_vector(l,1);
                    electromagneticBC(app.emagmodel,"Edge",absbc_edge,"FarField","absorbing", "Thickness",thicknessof_edge);
                end
            end
            R = solve(app.emagmodel);
            pdeplot(app.mesh_Hmax,XYData=R.MagneticPotential,FlowData=[R.MagneticField.Hx, R.MagneticField.Hy],colormap="hot");
            hFig1 = gcf; 
            hAx1 = gca; 
            copyobj(allchild(hAx1), app.UIAxes);
            view(app.UIAxes,2)
            colorbar(app.UIAxes)
            colormap(app.UIAxes,"hot");
            close(hFig1);
%{
            v = linspace(-7,7,51);
            [X,Y] = meshgrid(v);
            
            Hintrp = interpolateMagneticField(R,X,Y);
            HintrpX = reshape(Hintrp.Hx,size(X));
            HintrpY = reshape(Hintrp.Hy,size(Y));
            
            quiver(X,Y,HintrpX,HintrpY,"Color","red")
            hFig2 = gcf; 
            hAx2 = gca; 
            copyobj(allchild(hAx2), app.UIAxes4);
            view(app.UIAxes4,2)
            colorbar(app.UIAxes4)
            colormap(app.UIAxes4,"hot");
            close(hFig2);
%}
            A_diff = (max(R.MagneticPotential)-min(R.MagneticPotential));
            app.InductanceperunitlengthHmEditField.Value = A_diff;
        end

        % Value changed function: FilenameEditField
        function FilenameEditFieldValueChanged(app, event)
            value = app.FilenameEditField.Value;
        end

        % Button pushed function: LoadButton_15
        function LoadButton_15Pushed(app, event)
            app.emagmodel = createpde(electromagnetic="electrostatic");
            importGeometry(app.emagmodel,app.FilenameEditField.Value);
            pdegplot(app.emagmodel,EdgeLabels="on",FaceLabels="on")
            hFig = gcf;
            hAx = gca;
            copyobj(allchild(hAx), app.UIAxes3);
            view(app.UIAxes3,2);
            close(hFig);
            app.mesh_Hmax = generateMesh(app.emagmodel,"Hmax",0.2);
            pdemesh(app.mesh_Hmax);
            hFig2 = gcf; 
            hAx2 = gca; 
            copyobj(allchild(hAx2), app.UIAxes2);
            view(app.UIAxes2,2)
            close(hFig2);
        end

        % Value changed function: FilenameEditField_2
        function FilenameEditField_2ValueChanged(app, event)
            value = app.FilenameEditField_2.Value;
            
        end

        % Button pushed function: LoadButton_16
        function LoadButton_16Pushed(app, event)
            app.emagmodel = createpde(electromagnetic="magnetostatic");
            importGeometry(app.emagmodel,app.FilenameEditField_2.Value);
            pdegplot(app.emagmodel,EdgeLabels="on",FaceLabels="on")
            hFig = gcf;
            hAx = gca;
            copyobj(allchild(hAx), app.UIAxes3);
            view(app.UIAxes3,2);
            close(hFig);
            app.mesh_Hmax = generateMesh(app.emagmodel,"Hmax",0.2);
            pdemesh(app.mesh_Hmax);
            hFig2 = gcf; 
            hAx2 = gca; 
            copyobj(allchild(hAx2), app.UIAxes2);
            view(app.UIAxes2,2)
            close(hFig2);
        end

        % Display data changed function: UITable7_3
        function UITable7DisplayDataChanged(app, event)
            newDisplayData = app.UITable7_3.DisplayData;
            
        end

        % Value changed function: 
        % ActivateabsorbingboundaryconditionCheckBox_3
        function ActivateabsorbingboundaryconditionCheckBoxValueChanged(app, event)
            value = app.ActivateabsorbingboundaryconditionCheckBox_3.Value;
            
        end

        % Value changed function: 
        % NumberofabsorbingboundaryconditionEditField_3
        function NumberofabsorbingboundaryconditionEditFieldValueChanged(app, event)
            value = app.NumberofabsorbingboundaryconditionEditField_3.Value;
            
        end

        % Value changed function: ActivateboundaryconditionCheckBox
        function ActivateboundaryconditionCheckBoxValueChanged(app, event)
            value = app.ActivateboundaryconditionCheckBox.Value;
            
        end

        % Value changed function: ActivateboundaryconditionCheckBox_2
        function ActivateboundaryconditionCheckBox_2ValueChanged(app, event)
            value = app.ActivateboundaryconditionCheckBox_2.Value;
            
        end

        % Value changed function: ActivatesourceCheckBox
        function ActivatesourceCheckBoxValueChanged(app, event)
            value = app.ActivatesourceCheckBox.Value;
            
        end

        % Value changed function: ActivatesourceCheckBox_2
        function ActivatesourceCheckBox_2ValueChanged(app, event)
            value = app.ActivatesourceCheckBox_2.Value;
            
        end

        % Value changed function: 
        % ActivateabsorbingboundaryconditionCheckBox_4
        function ActivateabsorbingboundaryconditionCheckBox_4ValueChanged(app, event)
            value = app.ActivateabsorbingboundaryconditionCheckBox_4.Value;
            
        end

        % Button pushed function: LoadButton_18
        function LoadButton_18Pushed(app, event)
            s = app.NumberofabsorbingboundaryconditionEditField_4.Value;
            dt = zeros(s,2,"double");
            app.UITable7_4.Data= dt;
        end

        % Value changed function: 
        % NumberofabsorbingboundaryconditionEditField_4
        function NumberofabsorbingboundaryconditionEditField_4ValueChanged(app, event)
            value = app.NumberofabsorbingboundaryconditionEditField_4.Value;
            
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {480, 480};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {248, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.TabGroup3 = uitabgroup(app.LeftPanel);
            app.ElectrostaticTab = uitab(app.TabGroup3);
            app.ElectrostaticTab.Title = 'Electrostatic';
            app.TabGroup4 = uitabgroup(app.ElectrostaticTab);
            app.CreategeometryTab = uitab(app.TabGroup4);
            app.CreategeometryTab.Title = 'Create geometry';
            app.DrawgeometryandmeshButton = uibutton(app.CreategeometryTab, 'push');
            app.DrawgeometryandmeshButton.ButtonPushedFcn = createCallbackFcn(app, @DrawgeometryandmeshButtonPushed, true);
            app.DrawgeometryandmeshButton.Text = 'Draw geometry and mesh';
            app.SolveButton = uibutton(app.CreategeometryTab, 'push');
            app.SolveButton.Text = 'Solve';
            app.RefreshButton_2 = uibutton(app.CreategeometryTab, 'push');
            app.RefreshButton_2.ButtonPushedFcn = createCallbackFcn(app, @RefreshButton_2Pushed, true);
            app.RefreshButton_2.Text = 'Refresh';
            app.ClearallButton_2 = uibutton(app.CreategeometryTab, 'push');
            app.ClearallButton_2.Text = 'Clear all';
            app.TabGroup = uitabgroup(app.CreategeometryTab);
            app.rectangleTab = uitab(app.TabGroup);
            app.rectangleTab.Title = 'rectangle';
            app.RectanglenumberEditFieldLabel = uilabel(app.rectangleTab);
            app.RectanglenumberEditFieldLabel.Text = 'Rectangle number';
            app.RectanglenumberEditField = uieditfield(app.rectangleTab, 'numeric');
            app.RectanglenumberEditField.ValueChangedFcn = createCallbackFcn(app, @RectanglenumberEditFieldValueChanged, true);
            app.LoadButton_2 = uibutton(app.rectangleTab, 'push');
            app.LoadButton_2.ButtonPushedFcn = createCallbackFcn(app, @LoadButton_2Pushed, true);
            app.LoadButton_2.Text = 'Load';
            app.UITable2 = uitable(app.rectangleTab);
            app.UITable2.ColumnName = {'Edge length horizontal'; 'Edge length vertical'; 'Center coordinate X'; 'Center coordinate Y'; '1 for subtraction, 0 for addition'};
            app.UITable2.RowName = {};
            app.UITable2.ColumnEditable = true;
            app.UITable2.DisplayDataChangedFcn = createCallbackFcn(app, @UITable2DisplayDataChanged, true);
            app.ActivaterectangleCheckBox = uicheckbox(app.rectangleTab);
            app.ActivaterectangleCheckBox.ValueChangedFcn = createCallbackFcn(app, @ActivaterectangleCheckBoxValueChanged, true);
            app.ActivaterectangleCheckBox.Text = 'Activate rectangle';
            app.circleTab = uitab(app.TabGroup);
            app.circleTab.Title = 'circle';
            app.CirclenumberEditFieldLabel = uilabel(app.circleTab);
            app.CirclenumberEditFieldLabel.Text = 'Circle number';
            app.CirclenumberEditField = uieditfield(app.circleTab, 'numeric');
            app.CirclenumberEditField.ValueChangedFcn = createCallbackFcn(app, @CirclenumberEditFieldValueChanged, true);
            app.LoadButton_3 = uibutton(app.circleTab, 'push');
            app.LoadButton_3.ButtonPushedFcn = createCallbackFcn(app, @LoadButton_3Pushed, true);
            app.LoadButton_3.Text = 'Load';
            app.UITable3 = uitable(app.circleTab);
            app.UITable3.ColumnName = {'Radius'; 'Center coordinate X'; 'Center coordinate Y'};
            app.UITable3.RowName = {};
            app.UITable3.ColumnEditable = true;
            app.ActivatecircleCheckBox = uicheckbox(app.circleTab);
            app.ActivatecircleCheckBox.ValueChangedFcn = createCallbackFcn(app, @ActivatecircleCheckBoxValueChanged, true);
            app.ActivatecircleCheckBox.Text = 'Activate circle';
            app.triangleTab = uitab(app.TabGroup);
            app.triangleTab.Title = 'triangle';
            app.LoadButton = uibutton(app.triangleTab, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.LoadButton.Text = 'Load';
            app.UITable = uitable(app.triangleTab);
            app.UITable.ColumnName = {'Vertice 1 X'; 'Vertice 1 Y'; 'Vertice 2 X'; 'Vertice 2 Y'; 'Vertice 3 X'; 'Vertice 3 Y'};
            app.UITable.RowName = {};
            app.UITable.ColumnEditable = [true true true true true true];
            app.UITable.DisplayDataChangedFcn = createCallbackFcn(app, @UITableDisplayDataChanged, true);
            app.TrianglenumberTextAreaLabel = uilabel(app.triangleTab);
            app.TrianglenumberTextAreaLabel.Text = 'Triangle number';
            app.TrianglenumberTextArea = uitextarea(app.triangleTab);
            app.TrianglenumberTextArea.ValueChangedFcn = createCallbackFcn(app, @TrianglenumberTextAreaValueChanged, true);
            app.ActivatetriangleCheckBox = uicheckbox(app.triangleTab);
            app.ActivatetriangleCheckBox.ValueChangedFcn = createCallbackFcn(app, @ActivatetriangleCheckBoxValueChanged, true);
            app.ActivatetriangleCheckBox.Text = 'Activate triangle';
            app.parallelplateTab = uitab(app.TabGroup);
            app.parallelplateTab.Title = 'parallel plate';
            app.DrawButton_4 = uibutton(app.parallelplateTab, 'push');
            app.DrawButton_4.ButtonPushedFcn = createCallbackFcn(app, @DrawButton_4Pushed, true);
            app.DrawButton_4.Text = 'Draw';
            app.widthofplatesmEditFieldLabel = uilabel(app.parallelplateTab);
            app.widthofplatesmEditFieldLabel.Text = 'width of plates (m)';
            app.widthofplatesmEditField = uieditfield(app.parallelplateTab, 'numeric');
            app.widthofplatesmEditField.ValueChangedFcn = createCallbackFcn(app, @widthofplatesmEditFieldValueChanged, true);
            app.widthofplatesmEditField.Value = 4;
            app.distancebetweenplatesmEditFieldLabel = uilabel(app.parallelplateTab);
            app.distancebetweenplatesmEditFieldLabel.Text = 'distance between plates (m)';
            app.distancebetweenplatesmEditField = uieditfield(app.parallelplateTab, 'numeric');
            app.distancebetweenplatesmEditField.ValueChangedFcn = createCallbackFcn(app, @distancebetweenplatesmEditFieldValueChanged, true);
            app.distancebetweenplatesmEditField.Value = 4;
            app.relativepermittivityofdielectricLabel = uilabel(app.parallelplateTab);
            app.relativepermittivityofdielectricLabel.Text = {'relative permittivity'; 'of dielectric'};
            app.relativepermittivityofdielectricEditField = uieditfield(app.parallelplateTab, 'numeric');
            app.relativepermittivityofdielectricEditField.ValueChangedFcn = createCallbackFcn(app, @relativepermittivityofdielectricEditFieldValueChanged, true);
            app.relativepermittivityofdielectricEditField.Value = 1;
            app.ClearallButton = uibutton(app.parallelplateTab, 'push');
            app.ClearallButton.ButtonPushedFcn = createCallbackFcn(app, @ClearallButtonPushed, true);
            app.ClearallButton.Text = 'Clear all';
            app.platethicknessmEditFieldLabel = uilabel(app.parallelplateTab);
            app.platethicknessmEditFieldLabel.Text = 'plate thickness (m)';
            app.platethicknessmEditField = uieditfield(app.parallelplateTab, 'numeric');
            app.platethicknessmEditField.ValueChangedFcn = createCallbackFcn(app, @platethicknessmEditFieldValueChanged, true);
            app.platethicknessmEditField.Value = 0.01;
            app.chargedensityofoneplateQm2Label = uilabel(app.parallelplateTab);
            app.chargedensityofoneplateQm2Label.Text = {'charge density of'; ' (+) plate (Q/m^2)'};
            app.chargedensityofplateQm2EditField = uieditfield(app.parallelplateTab, 'numeric');
            app.chargedensityofplateQm2EditField.ValueChangedFcn = createCallbackFcn(app, @chargedensityofplateQm2EditFieldValueChanged, true);
            app.chargedensityofplateQm2EditField.Value = 25;
            app.ClearsolutionkeepgeometryButton = uibutton(app.parallelplateTab, 'push');
            app.ClearsolutionkeepgeometryButton.ButtonPushedFcn = createCallbackFcn(app, @ClearsolutionkeepgeometryButtonPushed, true);
            app.ClearsolutionkeepgeometryButton.Text = 'Clear solution keep geometry';
            app.coaxialcableTab = uitab(app.TabGroup);
            app.coaxialcableTab.Title = 'coaxial cable';
            app.DrawButton_3 = uibutton(app.coaxialcableTab, 'push');
            app.DrawButton_3.ButtonPushedFcn = createCallbackFcn(app, @DrawButton_3Pushed, true);
            app.DrawButton_3.Text = 'Draw';
            app.RefreshButton = uibutton(app.coaxialcableTab, 'push');
            app.RefreshButton.ButtonPushedFcn = createCallbackFcn(app, @RefreshButtonPushed, true);
            app.RefreshButton.Text = 'Refresh';
            app.ChargedensityofringLabel = uilabel(app.coaxialcableTab);
            app.ChargedensityofringLabel.Text = {'Charge density '; 'of  inner cable (+) '};
            app.ChargedensityofinnercableEditField = uieditfield(app.coaxialcableTab, 'numeric');
            app.ChargedensityofinnercableEditField.ValueChangedFcn = createCallbackFcn(app, @ChargedensityofinnercableEditFieldValueChanged, true);
            app.ChargedensityofinnercableEditField.Value = 0.884194128288307;
            app.RelativepermittivityofdielectricLabel = uilabel(app.coaxialcableTab);
            app.RelativepermittivityofdielectricLabel.Text = {'Relative permittivity'; ' of dielectric'};
            app.RelativepermittivityofdielectricEditField = uieditfield(app.coaxialcableTab, 'numeric');
            app.RelativepermittivityofdielectricEditField.ValueChangedFcn = createCallbackFcn(app, @RelativepermittivityofdielectricEditFieldValueChanged, true);
            app.RelativepermittivityofdielectricEditField.Value = 1;
            app.ThicknessofinnerringEditFieldLabel = uilabel(app.coaxialcableTab);
            app.ThicknessofinnerringEditFieldLabel.Text = {'Thickness of'; ' inner ring'};
            app.ThicknessofinnerringEditField = uieditfield(app.coaxialcableTab, 'numeric');
            app.ThicknessofinnerringEditField.ValueChangedFcn = createCallbackFcn(app, @ThicknessofinnerringEditFieldValueChanged, true);
            app.ThicknessofinnerringEditField.Value = 0.2;
            app.ThicknessofdielectricmediaLabel = uilabel(app.coaxialcableTab);
            app.ThicknessofdielectricmediaLabel.Text = {'Thickness of '; 'dielectric media'};
            app.ThicknessofdielectricmediaEditField = uieditfield(app.coaxialcableTab, 'numeric');
            app.ThicknessofdielectricmediaEditField.ValueChangedFcn = createCallbackFcn(app, @ThicknessofdielectricmediaEditFieldValueChanged, true);
            app.ThicknessofdielectricmediaEditField.Value = 7;
            app.ThicknessofouterringEditFieldLabel = uilabel(app.coaxialcableTab);
            app.ThicknessofouterringEditFieldLabel.Text = {'Thickness of '; 'outer ring'};
            app.ThicknessofouterringEditField = uieditfield(app.coaxialcableTab, 'numeric');
            app.ThicknessofouterringEditField.ValueChangedFcn = createCallbackFcn(app, @ThicknessofouterringEditFieldValueChanged, true);
            app.ThicknessofouterringEditField.Value = 0.2;
            app.InnerconductorradiusLabel = uilabel(app.coaxialcableTab);
            app.InnerconductorradiusLabel.Text = {'Inner conductor '; 'radius'};
            app.InnerconductorradiusEditField = uieditfield(app.coaxialcableTab, 'numeric');
            app.InnerconductorradiusEditField.ValueChangedFcn = createCallbackFcn(app, @InnerconductorradiusEditFieldValueChanged, true);
            app.InnerconductorradiusEditField.Value = 0.8;
            app.ImportgeometryTab = uitab(app.TabGroup);
            app.ImportgeometryTab.Title = 'Import geometry';
            app.FilenameEditFieldLabel = uilabel(app.ImportgeometryTab);
            app.FilenameEditFieldLabel.Text = 'File name';
            app.FilenameEditField = uieditfield(app.ImportgeometryTab, 'text');
            app.FilenameEditField.ValueChangedFcn = createCallbackFcn(app, @FilenameEditFieldValueChanged, true);
            app.LoadButton_15 = uibutton(app.ImportgeometryTab, 'push');
            app.LoadButton_15.ButtonPushedFcn = createCallbackFcn(app, @LoadButton_15Pushed, true);
            app.LoadButton_15.Text = 'Load';
            app.AssignEMpropertiesTab = uitab(app.TabGroup4);
            app.AssignEMpropertiesTab.Title = 'Assign EM properties';
            app.TabGroup5 = uitabgroup(app.AssignEMpropertiesTab);
            app.SourceTab = uitab(app.TabGroup5);
            app.SourceTab.Title = 'Source';
            app.UITable4 = uitable(app.SourceTab);
            app.UITable4.ColumnName = {'Define face'; 'Define charge density'};
            app.UITable4.RowName = {};
            app.UITable4.ColumnEditable = true;
            app.UITable4.DisplayDataChangedFcn = createCallbackFcn(app, @UITable4DisplayDataChanged, true);
            app.NumberoffacestogivechargedensityEditFieldLabel = uilabel(app.SourceTab);
            app.NumberoffacestogivechargedensityEditFieldLabel.Text = {'Number of faces to'; ' give charge density'};
            app.NumberoffacestogivechargedensityEditField = uieditfield(app.SourceTab, 'numeric');
            app.NumberoffacestogivechargedensityEditField.ValueChangedFcn = createCallbackFcn(app, @NumberoffacestogivechargedensityEditFieldValueChanged, true);
            app.LoadButton_4 = uibutton(app.SourceTab, 'push');
            app.LoadButton_4.ButtonPushedFcn = createCallbackFcn(app, @LoadButton_4Pushed, true);
            app.LoadButton_4.Text = 'Load';
            app.ActivatesourceCheckBox_2 = uicheckbox(app.SourceTab);
            app.ActivatesourceCheckBox_2.ValueChangedFcn = createCallbackFcn(app, @ActivatesourceCheckBox_2ValueChanged, true);
            app.ActivatesourceCheckBox_2.Text = 'Activate source';
            app.BoundaryconditionTab = uitab(app.TabGroup5);
            app.BoundaryconditionTab.Title = 'Boundary condition';
            app.NumberofboundaryconditionEditFieldLabel = uilabel(app.BoundaryconditionTab);
            app.NumberofboundaryconditionEditFieldLabel.Text = {'Number of '; 'boundary condition'};
            app.NumberofboundaryconditionEditField = uieditfield(app.BoundaryconditionTab, 'numeric');
            app.NumberofboundaryconditionEditField.ValueChangedFcn = createCallbackFcn(app, @NumberofboundaryconditionEditFieldValueChanged, true);
            app.LoadButton_5 = uibutton(app.BoundaryconditionTab, 'push');
            app.LoadButton_5.ButtonPushedFcn = createCallbackFcn(app, @LoadButton_5Pushed, true);
            app.LoadButton_5.Text = 'Load';
            app.UITable5 = uitable(app.BoundaryconditionTab);
            app.UITable5.ColumnName = {'Define edge'; 'Define boundary condition'};
            app.UITable5.RowName = {};
            app.UITable5.ColumnEditable = true;
            app.UITable5.DisplayDataChangedFcn = createCallbackFcn(app, @UITable5DisplayDataChanged, true);
            app.ActivateboundaryconditionCheckBox = uicheckbox(app.BoundaryconditionTab);
            app.ActivateboundaryconditionCheckBox.ValueChangedFcn = createCallbackFcn(app, @ActivateboundaryconditionCheckBoxValueChanged, true);
            app.ActivateboundaryconditionCheckBox.Text = 'Activate boundary condition';
            app.FarfieldTab = uitab(app.TabGroup5);
            app.FarfieldTab.Title = 'Far field';
            app.UITable7_3 = uitable(app.FarfieldTab);
            app.UITable7_3.ColumnName = {'Define edge'; 'Define thickness'};
            app.UITable7_3.RowName = {};
            app.UITable7_3.ColumnEditable = true;
            app.UITable7_3.DisplayDataChangedFcn = createCallbackFcn(app, @UITable7DisplayDataChanged, true);
            app.ActivateabsorbingboundaryconditionCheckBox_3 = uicheckbox(app.FarfieldTab);
            app.ActivateabsorbingboundaryconditionCheckBox_3.ValueChangedFcn = createCallbackFcn(app, @ActivateabsorbingboundaryconditionCheckBoxValueChanged, true);
            app.ActivateabsorbingboundaryconditionCheckBox_3.Text = {'Activate absorbing'; 'boundary condition '};
            app.LoadButton_17 = uibutton(app.FarfieldTab, 'push');
            app.LoadButton_17.ButtonPushedFcn = createCallbackFcn(app, @LoadButton_7Pushed, true);
            app.LoadButton_17.Text = 'Load';
            app.NumberofabsorbingboundaryconditionEditField_3Label = uilabel(app.FarfieldTab);
            app.NumberofabsorbingboundaryconditionEditField_3Label.Text = {'Number of absorbing'; 'boundary condition'};
            app.NumberofabsorbingboundaryconditionEditField_3 = uieditfield(app.FarfieldTab, 'numeric');
            app.NumberofabsorbingboundaryconditionEditField_3.ValueChangedFcn = createCallbackFcn(app, @NumberofabsorbingboundaryconditionEditFieldValueChanged, true);
            app.RelativepermittivityTab = uitab(app.TabGroup5);
            app.RelativepermittivityTab.Title = 'Relative permittivity';
            app.NumberoffacestogiverelativepermittivityLabel = uilabel(app.RelativepermittivityTab);
            app.NumberoffacestogiverelativepermittivityLabel.Text = {'Number of faces to give'; ' relative permittivity'};
            app.NumberoffacestogiverelativepermittivityEditField = uieditfield(app.RelativepermittivityTab, 'numeric');
            app.NumberoffacestogiverelativepermittivityEditField.ValueChangedFcn = createCallbackFcn(app, @NumberoffacestogiverelativepermittivityEditFieldValueChanged, true);
            app.LoadButton_6 = uibutton(app.RelativepermittivityTab, 'push');
            app.LoadButton_6.ButtonPushedFcn = createCallbackFcn(app, @LoadButton_6Pushed, true);
            app.LoadButton_6.Text = 'Load';
            app.UITable6 = uitable(app.RelativepermittivityTab);
            app.UITable6.ColumnName = {'Define face'; 'Define relative permittivity'};
            app.UITable6.RowName = {};
            app.UITable6.ColumnEditable = true;
            app.UITable6.DisplayDataChangedFcn = createCallbackFcn(app, @UITable6DisplayDataChanged, true);
            app.TextArea_2Label = uilabel(app.RelativepermittivityTab);
            app.TextArea_2Label.Text = 'Text Area';
            app.TextArea_2 = uitextarea(app.RelativepermittivityTab);
            app.TextArea_2.Value = {'Hint: Maximum value is 1e99'};
            app.SolveButton_2 = uibutton(app.AssignEMpropertiesTab, 'push');
            app.SolveButton_2.ButtonPushedFcn = createCallbackFcn(app, @SolveButton_2Pushed, true);
            app.SolveButton_2.Text = 'Solve';
            app.MagnetostaticTab = uitab(app.TabGroup3);
            app.MagnetostaticTab.Title = 'Magnetostatic';
            app.TabGroup6 = uitabgroup(app.MagnetostaticTab);
            app.CreategeometryTab_2 = uitab(app.TabGroup6);
            app.CreategeometryTab_2.Title = ' Create geometry';
            app.TabGroup7 = uitabgroup(app.CreategeometryTab_2);
            app.RectangleTab = uitab(app.TabGroup7);
            app.RectangleTab.Title = 'Rectangle';
            app.LoadButton_8 = uibutton(app.RectangleTab, 'push');
            app.LoadButton_8.ButtonPushedFcn = createCallbackFcn(app, @LoadButton_8Pushed, true);
            app.LoadButton_8.Text = 'Load';
            app.UITable2_2 = uitable(app.RectangleTab);
            app.UITable2_2.ColumnName = {'Edge length horizontal'; 'Edge length vertical'; 'Center coordinate X'; 'Center coordinate Y'; '1 for subtraction, 0 for addition'};
            app.UITable2_2.RowName = {};
            app.UITable2_2.ColumnEditable = true;
            app.UITable2_2.DisplayDataChangedFcn = createCallbackFcn(app, @UITable2_2DisplayDataChanged, true);
            app.ActivaterectangleCheckBox_2 = uicheckbox(app.RectangleTab);
            app.ActivaterectangleCheckBox_2.ValueChangedFcn = createCallbackFcn(app, @ActivaterectangleCheckBox_2ValueChanged, true);
            app.ActivaterectangleCheckBox_2.Text = 'Activate rectangle';
            app.RectanglenumberEditField_2Label = uilabel(app.RectangleTab);
            app.RectanglenumberEditField_2Label.Text = 'Rectangle number';
            app.RectanglenumberEditField_2 = uieditfield(app.RectangleTab, 'numeric');
            app.RectanglenumberEditField_2.ValueChangedFcn = createCallbackFcn(app, @RectanglenumberEditField_2ValueChanged, true);
            app.CircleTab = uitab(app.TabGroup7);
            app.CircleTab.Title = 'Circle';
            app.LoadButton_9 = uibutton(app.CircleTab, 'push');
            app.LoadButton_9.ButtonPushedFcn = createCallbackFcn(app, @LoadButton_9Pushed, true);
            app.LoadButton_9.Text = 'Load';
            app.UITable3_2 = uitable(app.CircleTab);
            app.UITable3_2.ColumnName = {'Radius'; 'Center coordinate X'; 'Center coordinate Y'};
            app.UITable3_2.RowName = {};
            app.UITable3_2.ColumnEditable = true;
            app.UITable3_2.DisplayDataChangedFcn = createCallbackFcn(app, @UITable3_2DisplayDataChanged, true);
            app.ActivatecircleCheckBox_2 = uicheckbox(app.CircleTab);
            app.ActivatecircleCheckBox_2.ValueChangedFcn = createCallbackFcn(app, @ActivatecircleCheckBox_2ValueChanged, true);
            app.ActivatecircleCheckBox_2.Text = 'Activate circle';
            app.CirclenumberEditField_2Label = uilabel(app.CircleTab);
            app.CirclenumberEditField_2Label.Text = 'Circle number';
            app.CirclenumberEditField_2 = uieditfield(app.CircleTab, 'numeric');
            app.CirclenumberEditField_2.ValueChangedFcn = createCallbackFcn(app, @CirclenumberEditField_2ValueChanged, true);
            app.TriangleTab = uitab(app.TabGroup7);
            app.TriangleTab.Title = 'Triangle';
            app.LoadButton_10 = uibutton(app.TriangleTab, 'push');
            app.LoadButton_10.ButtonPushedFcn = createCallbackFcn(app, @LoadButton_10Pushed, true);
            app.LoadButton_10.Text = 'Load';
            app.UITable_2 = uitable(app.TriangleTab);
            app.UITable_2.ColumnName = {'Vertice 1 X'; 'Vertice 1 Y'; 'Vertice 2 X'; 'Vertice 2 Y'; 'Vertice 3 X'; 'Vertice 3 Y'};
            app.UITable_2.RowName = {};
            app.UITable_2.ColumnEditable = [true true true true true true];
            app.UITable_2.DisplayDataChangedFcn = createCallbackFcn(app, @UITable_2DisplayDataChanged, true);
            app.ActivatetriangleCheckBox_2 = uicheckbox(app.TriangleTab);
            app.ActivatetriangleCheckBox_2.ValueChangedFcn = createCallbackFcn(app, @ActivatetriangleCheckBox_2ValueChanged, true);
            app.ActivatetriangleCheckBox_2.Text = 'Activate triangle';
            app.TrianglenumberEditFieldLabel = uilabel(app.TriangleTab);
            app.TrianglenumberEditFieldLabel.Text = 'Triangle number';
            app.TrianglenumberEditField = uieditfield(app.TriangleTab, 'numeric');
            app.TrianglenumberEditField.ValueChangedFcn = createCallbackFcn(app, @TrianglenumberEditFieldValueChanged, true);
            app.CoaxialcableTab = uitab(app.TabGroup7);
            app.CoaxialcableTab.Title = 'Coaxial cable';
            app.DrawButton_5 = uibutton(app.CoaxialcableTab, 'push');
            app.DrawButton_5.ButtonPushedFcn = createCallbackFcn(app, @DrawButton_5Pushed, true);
            app.DrawButton_5.Text = 'Draw';
            app.RefreshButton_3 = uibutton(app.CoaxialcableTab, 'push');
            app.RefreshButton_3.ButtonPushedFcn = createCallbackFcn(app, @RefreshButton_3Pushed, true);
            app.RefreshButton_3.Text = 'Refresh';
            app.CurrentdensityofinnercableEditFieldLabel = uilabel(app.CoaxialcableTab);
            app.CurrentdensityofinnercableEditFieldLabel.Text = {'Current density '; 'of  inner cable (+) '};
            app.CurrentdensityofinnercableEditField = uieditfield(app.CoaxialcableTab, 'numeric');
            app.CurrentdensityofinnercableEditField.ValueChangedFcn = createCallbackFcn(app, @CurrentdensityofinnercableEditFieldValueChanged, true);
            app.CurrentdensityofinnercableEditField.Value = 0.884194128288307;
            app.RelativepermeabilityofdielectricEditFieldLabel = uilabel(app.CoaxialcableTab);
            app.RelativepermeabilityofdielectricEditFieldLabel.Text = {'Relative permeability'; ' of dielectric'};
            app.RelativepermeabilityofdielectricEditField = uieditfield(app.CoaxialcableTab, 'numeric');
            app.RelativepermeabilityofdielectricEditField.ValueChangedFcn = createCallbackFcn(app, @RelativepermeabilityofdielectricEditFieldValueChanged, true);
            app.RelativepermeabilityofdielectricEditField.Value = 1;
            app.ThicknessofinnerringEditField_2Label = uilabel(app.CoaxialcableTab);
            app.ThicknessofinnerringEditField_2Label.Text = {'Thickness of'; ' inner ring'};
            app.ThicknessofinnerringEditField_2 = uieditfield(app.CoaxialcableTab, 'numeric');
            app.ThicknessofinnerringEditField_2.ValueChangedFcn = createCallbackFcn(app, @ThicknessofinnerringEditField_2ValueChanged, true);
            app.ThicknessofinnerringEditField_2.Value = 0.2;
            app.ThicknessofdielectricmediaEditField_2Label = uilabel(app.CoaxialcableTab);
            app.ThicknessofdielectricmediaEditField_2Label.Text = {'Thickness of '; 'dielectric media'};
            app.ThicknessofdielectricmediaEditField_2 = uieditfield(app.CoaxialcableTab, 'numeric');
            app.ThicknessofdielectricmediaEditField_2.ValueChangedFcn = createCallbackFcn(app, @ThicknessofdielectricmediaEditField_2ValueChanged, true);
            app.ThicknessofdielectricmediaEditField_2.Value = 7;
            app.ThicknessofouterringEditField_2Label = uilabel(app.CoaxialcableTab);
            app.ThicknessofouterringEditField_2Label.Text = {'Thickness of '; 'outer ring'};
            app.ThicknessofouterringEditField_2 = uieditfield(app.CoaxialcableTab, 'numeric');
            app.ThicknessofouterringEditField_2.ValueChangedFcn = createCallbackFcn(app, @ThicknessofouterringEditField_2ValueChanged, true);
            app.ThicknessofouterringEditField_2.Value = 0.2;
            app.InnerconductorradiusEditField_2Label = uilabel(app.CoaxialcableTab);
            app.InnerconductorradiusEditField_2Label.Text = {'Inner conductor '; 'radius'};
            app.InnerconductorradiusEditField_2 = uieditfield(app.CoaxialcableTab, 'numeric');
            app.InnerconductorradiusEditField_2.ValueChangedFcn = createCallbackFcn(app, @InnerconductorradiusEditField_2ValueChanged, true);
            app.InnerconductorradiusEditField_2.Value = 0.8;
            app.ImportgeometryTab_2 = uitab(app.TabGroup7);
            app.ImportgeometryTab_2.Title = 'Import geometry';
            app.LoadButton_16 = uibutton(app.ImportgeometryTab_2, 'push');
            app.LoadButton_16.ButtonPushedFcn = createCallbackFcn(app, @LoadButton_16Pushed, true);
            app.LoadButton_16.Text = 'Load';
            app.FilenameEditField_2Label = uilabel(app.ImportgeometryTab_2);
            app.FilenameEditField_2Label.Text = 'File name';
            app.FilenameEditField_2 = uieditfield(app.ImportgeometryTab_2, 'text');
            app.FilenameEditField_2.ValueChangedFcn = createCallbackFcn(app, @FilenameEditField_2ValueChanged, true);
            app.DrawgeometryandmeshButton_2 = uibutton(app.CreategeometryTab_2, 'push');
            app.DrawgeometryandmeshButton_2.ButtonPushedFcn = createCallbackFcn(app, @DrawgeometryandmeshButton_2Pushed, true);
            app.DrawgeometryandmeshButton_2.Text = 'Draw geometry and mesh';
            app.SolveButton_3 = uibutton(app.CreategeometryTab_2, 'push');
            app.SolveButton_3.Text = 'Solve';
            app.RefreshButton_4 = uibutton(app.CreategeometryTab_2, 'push');
            app.RefreshButton_4.ButtonPushedFcn = createCallbackFcn(app, @RefreshButton_4Pushed, true);
            app.RefreshButton_4.Text = 'Refresh';
            app.ClearallButton_3 = uibutton(app.CreategeometryTab_2, 'push');
            app.ClearallButton_3.Text = 'Clear all';
            app.AssignEMpropertiesTab_2 = uitab(app.TabGroup6);
            app.AssignEMpropertiesTab_2.Title = 'Assign EM properties';
            app.TabGroup8 = uitabgroup(app.AssignEMpropertiesTab_2);
            app.SourceTab_2 = uitab(app.TabGroup8);
            app.SourceTab_2.Title = 'Source';
            app.UITable4_2 = uitable(app.SourceTab_2);
            app.UITable4_2.ColumnName = {'Define face'; 'Define current density'};
            app.UITable4_2.RowName = {};
            app.UITable4_2.ColumnEditable = true;
            app.UITable4_2.DisplayDataChangedFcn = createCallbackFcn(app, @UITable4_2DisplayDataChanged, true);
            app.LoadButton_11 = uibutton(app.SourceTab_2, 'push');
            app.LoadButton_11.ButtonPushedFcn = createCallbackFcn(app, @LoadButton_11Pushed, true);
            app.LoadButton_11.Text = 'Load';
            app.NumberoffacestogivecurrentdensityEditFieldLabel = uilabel(app.SourceTab_2);
            app.NumberoffacestogivecurrentdensityEditFieldLabel.Text = {'Number of faces to'; ' give current density'};
            app.NumberoffacestogivecurrentdensityEditField = uieditfield(app.SourceTab_2, 'numeric');
            app.NumberoffacestogivecurrentdensityEditField.ValueChangedFcn = createCallbackFcn(app, @NumberoffacestogivecurrentdensityEditFieldValueChanged, true);
            app.ActivatesourceCheckBox = uicheckbox(app.SourceTab_2);
            app.ActivatesourceCheckBox.ValueChangedFcn = createCallbackFcn(app, @ActivatesourceCheckBoxValueChanged, true);
            app.ActivatesourceCheckBox.Text = 'Activate source';
            app.BoundaryconditionTab_2 = uitab(app.TabGroup8);
            app.BoundaryconditionTab_2.Title = 'Boundary condition';
            app.LoadButton_12 = uibutton(app.BoundaryconditionTab_2, 'push');
            app.LoadButton_12.ButtonPushedFcn = createCallbackFcn(app, @LoadButton_12Pushed, true);
            app.LoadButton_12.Text = 'Load';
            app.UITable5_2 = uitable(app.BoundaryconditionTab_2);
            app.UITable5_2.ColumnName = {'Define edge'; 'Define boundary condition'};
            app.UITable5_2.RowName = {};
            app.UITable5_2.ColumnEditable = true;
            app.UITable5_2.DisplayDataChangedFcn = createCallbackFcn(app, @UITable5_2DisplayDataChanged, true);
            app.NumberofboundaryconditionEditField_2Label = uilabel(app.BoundaryconditionTab_2);
            app.NumberofboundaryconditionEditField_2Label.Text = {'Number of '; 'boundary condition'};
            app.NumberofboundaryconditionEditField_2 = uieditfield(app.BoundaryconditionTab_2, 'numeric');
            app.NumberofboundaryconditionEditField_2.ValueChangedFcn = createCallbackFcn(app, @NumberofboundaryconditionEditField_2ValueChanged, true);
            app.ActivateboundaryconditionCheckBox_2 = uicheckbox(app.BoundaryconditionTab_2);
            app.ActivateboundaryconditionCheckBox_2.ValueChangedFcn = createCallbackFcn(app, @ActivateboundaryconditionCheckBox_2ValueChanged, true);
            app.ActivateboundaryconditionCheckBox_2.Text = 'Activate boundary condition';
            app.FarfieldTab_2 = uitab(app.TabGroup8);
            app.FarfieldTab_2.Title = 'Far field';
            app.UITable7_4 = uitable(app.FarfieldTab_2);
            app.UITable7_4.ColumnName = {'Define edge'; 'Define thickness'};
            app.UITable7_4.RowName = {};
            app.UITable7_4.ColumnEditable = true;
            app.ActivateabsorbingboundaryconditionCheckBox_4 = uicheckbox(app.FarfieldTab_2);
            app.ActivateabsorbingboundaryconditionCheckBox_4.ValueChangedFcn = createCallbackFcn(app, @ActivateabsorbingboundaryconditionCheckBox_4ValueChanged, true);
            app.ActivateabsorbingboundaryconditionCheckBox_4.Text = {'Activate absorbing'; 'boundary condition '};
            app.LoadButton_18 = uibutton(app.FarfieldTab_2, 'push');
            app.LoadButton_18.ButtonPushedFcn = createCallbackFcn(app, @LoadButton_18Pushed, true);
            app.LoadButton_18.Text = 'Load';
            app.NumberofabsorbingboundaryconditionEditField_4Label = uilabel(app.FarfieldTab_2);
            app.NumberofabsorbingboundaryconditionEditField_4Label.Text = {'Number of absorbing'; 'boundary condition'};
            app.NumberofabsorbingboundaryconditionEditField_4 = uieditfield(app.FarfieldTab_2, 'numeric');
            app.NumberofabsorbingboundaryconditionEditField_4.ValueChangedFcn = createCallbackFcn(app, @NumberofabsorbingboundaryconditionEditField_4ValueChanged, true);
            app.RelativepermeabilityTab = uitab(app.TabGroup8);
            app.RelativepermeabilityTab.Title = 'Relative permeability';
            app.LoadButton_14 = uibutton(app.RelativepermeabilityTab, 'push');
            app.LoadButton_14.ButtonPushedFcn = createCallbackFcn(app, @LoadButton_14Pushed, true);
            app.LoadButton_14.Text = 'Load';
            app.UITable6_2 = uitable(app.RelativepermeabilityTab);
            app.UITable6_2.ColumnName = {'Define face'; 'Define relative permeability'};
            app.UITable6_2.RowName = {};
            app.UITable6_2.ColumnEditable = true;
            app.UITable6_2.DisplayDataChangedFcn = createCallbackFcn(app, @UITable6_2DisplayDataChanged, true);
            app.NumberoffacestogiverelativepermeabilityEditFieldLabel = uilabel(app.RelativepermeabilityTab);
            app.NumberoffacestogiverelativepermeabilityEditFieldLabel.Text = {'Number of faces to give'; ' relative permeability'};
            app.NumberoffacestogiverelativepermeabilityEditField = uieditfield(app.RelativepermeabilityTab, 'numeric');
            app.NumberoffacestogiverelativepermeabilityEditField.ValueChangedFcn = createCallbackFcn(app, @NumberoffacestogiverelativepermeabilityEditFieldValueChanged, true);
            app.TextAreaLabel = uilabel(app.RelativepermeabilityTab);
            app.TextAreaLabel.Text = 'Text Area';
            app.TextArea = uitextarea(app.RelativepermeabilityTab);
            app.TextArea.Value = {'Hint: Maximum value is 1e99'};
            app.SolveButton_4 = uibutton(app.AssignEMpropertiesTab_2, 'push');
            app.SolveButton_4.ButtonPushedFcn = createCallbackFcn(app, @SolveButton_4Pushed, true);
            app.SolveButton_4.Text = 'Solve';
            app.ClearfieldsButton = uibutton(app.AssignEMpropertiesTab_2, 'push');
            app.ClearfieldsButton.Text = 'Clear fields';
            app.RightPanel = uipanel(app.GridLayout);
            app.TabGroup2 = uitabgroup(app.RightPanel);
            app.geometryTab = uitab(app.TabGroup2);
            app.geometryTab.Title = 'geometry';
            app.UIAxes3 = uiaxes(app.geometryTab);
            title(app.UIAxes3, 'Geometry')
            xlabel(app.UIAxes3, 'X')
            ylabel(app.UIAxes3, 'Y')
            zlabel(app.UIAxes3, 'Z')
            app.meshTab = uitab(app.TabGroup2);
            app.meshTab.Title = 'mesh';
            app.UIAxes2 = uiaxes(app.meshTab);
            title(app.UIAxes2, 'Mesh')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.potentialvectorfieldTab = uitab(app.TabGroup2);
            app.potentialvectorfieldTab.Title = 'potential & vector field';
            app.UIAxes = uiaxes(app.potentialvectorfieldTab);
            title(app.UIAxes, 'Field')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.capacitanceperunitlengthFmEditFieldLabel = uilabel(app.potentialvectorfieldTab);
            app.capacitanceperunitlengthFmEditFieldLabel.Text = 'capacitance per unit length (F/m)';
            app.capacitanceperunitlengthFmEditField = uieditfield(app.potentialvectorfieldTab, 'numeric');
            app.capacitanceperunitlengthFmEditField.ValueChangedFcn = createCallbackFcn(app, @capacitanceperunitlengthFmEditFieldValueChanged, true);
            app.capacitanceperunitlengthFmEditField.Editable = 'off';
            app.InductanceperunitlengthHmEditFieldLabel = uilabel(app.potentialvectorfieldTab);
            app.InductanceperunitlengthHmEditFieldLabel.Text = 'Inductance per unit length (H/m)';
            app.InductanceperunitlengthHmEditField = uieditfield(app.potentialvectorfieldTab, 'numeric');
            app.InductanceperunitlengthHmEditField.ValueChangedFcn = createCallbackFcn(app, @InductanceperunitlengthHmEditFieldValueChanged, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = user_interface

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end